classdef winConfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        Folders_Grid              matlab.ui.container.GridLayout
        FolderMapPanel            matlab.ui.container.Panel
        FolderMapGrid             matlab.ui.container.GridLayout
        userPathButton            matlab.ui.control.Image
        userPath                  matlab.ui.control.EditField
        userPathLabel             matlab.ui.control.Label
        DataHubPOSTButton         matlab.ui.control.Image
        DataHubPOST               matlab.ui.control.EditField
        DataHubPOSTLabel          matlab.ui.control.Label
        config_FolderMapLabel     matlab.ui.control.Label
        CustomPlotGrid            matlab.ui.container.GridLayout
        graphics_Refresh          matlab.ui.control.Image
        general_ElevationPanel    matlab.ui.container.Panel
        general_ElevationGrid     matlab.ui.container.GridLayout
        ElevationForceSearch      matlab.ui.control.CheckBox
        ElevationNPoints          matlab.ui.control.DropDown
        ElevationNPointsLabel     matlab.ui.control.Label
        ElevationAPIServer        matlab.ui.control.DropDown
        ElevationAPIServerLabel   matlab.ui.control.Label
        general_ElevationRefresh  matlab.ui.control.Image
        general_ElevationLabel    matlab.ui.control.Label
        general_GraphicsPanel     matlab.ui.container.Panel
        general_GraphicsGrid      matlab.ui.container.GridLayout
        imgResolution             matlab.ui.control.DropDown
        imgResolutionLabel        matlab.ui.control.Label
        imgFormat                 matlab.ui.control.DropDown
        imgFormatLabel            matlab.ui.control.Label
        general_GraphicsLabel     matlab.ui.control.Label
        general_FilePanel         matlab.ui.container.Panel
        general_FileLabel         matlab.ui.control.Label
        General_Grid              matlab.ui.container.GridLayout
        openAuxiliarApp2Debug     matlab.ui.control.CheckBox
        openAuxiliarAppAsDocked   matlab.ui.control.CheckBox
        gpuType                   matlab.ui.control.DropDown
        gpuTypeLabel              matlab.ui.control.Label
        versionInfo               matlab.ui.control.Label
        versionInfoRefresh        matlab.ui.control.Image
        versionInfoLabel          matlab.ui.control.Label
        LeftPanelRadioGroup       matlab.ui.container.ButtonGroup
        btnFolder                 matlab.ui.control.RadioButton
        btnAnalysis               matlab.ui.control.RadioButton
        btnGeneral                matlab.ui.control.RadioButton
        LeftPanel                 matlab.ui.container.Panel
        LeftPanelGrid             matlab.ui.container.GridLayout
        menuUnderline             matlab.ui.control.Image
        menu_ButtonGrid           matlab.ui.container.GridLayout
        menu_ButtonIcon           matlab.ui.control.Image
        menu_ButtonLabel          matlab.ui.control.Label
        toolGrid                  matlab.ui.container.GridLayout
        openDevTools              matlab.ui.control.Image
        tool_RFDataHubButton      matlab.ui.control.Image
    end

    
    properties
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        
        mainApp
        rootFolder

        % A função do timer é executada uma única vez após a renderização
        % da figura, lendo arquivos de configuração, iniciando modo de operação
        % paralelo etc. A ideia é deixar o MATLAB focar apenas na criação dos 
        % componentes essenciais da GUI (especificados em "createComponents"), 
        % mostrando a GUI para o usuário o mais rápido possível.
        timerObj
        jsBackDoor

        % Janela de progresso já criada no DOM. Dessa forma, controla-se 
        % apenas a sua visibilidade - e tornando desnecessário criá-la a
        % cada chamada (usando uiprogressdlg, por exemplo).
        progressDialog

        stableVersion
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        DefaultValues = struct('Graphics',  struct('openGL', 'hardware', 'Format', 'jpeg', 'Resolution', '120', 'Dock', true),                                                                                                                                                                                                        ...
                               'Elevation', struct('Points', '256', 'ForceSearch', false, 'Server', 'Open-Elevation'))
    end


    methods
        %-----------------------------------------------------------------%
        % IPC: COMUNICAÇÃO ENTRE PROCESSOS
        %-----------------------------------------------------------------%
        function ipcSecundaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        startup_Controller(app)
                    otherwise
                        error('UnexpectedEvent')
                end

            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            end
        end
    end
    

    methods (Access = private)
        %-----------------------------------------------------------------%
        % JSBACKDOOR
        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor = uihtml(app.UIFigure, "HTMLSource",           appUtil.jsBackDoorHTMLSource(),                 ...
                                                  "HTMLEventReceivedFcn", @(~, evt)ipcSecundaryJSEventsHandler(app, evt), ...
                                                  "Visible",              "off");
        end

        %-----------------------------------------------------------------%
        function jsBackDoor_Customizations(app)
            if app.isDocked
                app.progressDialog = app.mainApp.progressDialog;
            else
                sendEventToHTMLSource(app.jsBackDoor, 'startup', app.mainApp.executionMode);
                app.progressDialog = ccTools.ProgressDialog(app.jsBackDoor);
            end

            elToModify = {app.versionInfo};
            elDataTag  = ui.CustomizationBase.getElementsDataTag(elToModify);
            if ~isempty(elDataTag)
                appName = class(app);
                ui.TextView.startup(app.jsBackDoor, elToModify{1}, appName);
            end
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_timerCreation(app)
            app.timerObj = timer("ExecutionMode", "fixedSpacing", ...
                                 "StartDelay",    1.5,            ...
                                 "Period",        .1,             ...
                                 "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj)
        end

        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj)
                delete(app.timerObj)
                
                jsBackDoor_Initialization(app)
            end
        end

        %-----------------------------------------------------------------%
        function startup_Controller(app)
            drawnow
            jsBackDoor_Customizations(app)

            startup_GUIComponents(app)
        end

        %-----------------------------------------------------------------%
        function startup_GUIComponents(app)
            switch app.mainApp.executionMode
                case 'webApp'
                    delete(app.openDevTools)
                otherwise
                    app.btnFolder.Enable               = 1;
                    app.versionInfoRefresh.Enable      = 1;
                    app.gpuType.Enable                 = 1;
                    app.openAuxiliarAppAsDocked.Enable = 1;
            end

            if ~isdeployed
                app.openAuxiliarApp2Debug.Enable = 1;
            end

            General_updatePanel(app)
            Elevation_updatePanel(app)
            Folder_updatePanel(app)
        end

        %-----------------------------------------------------------------%
        function General_updatePanel(app)
            % Versão:
            ui.TextView.update(app.versionInfo, util.HtmlTextGenerator.AppInfo(app.mainApp.General, app.mainApp.rootFolder, app.mainApp.executionMode));

            % Renderizador:
            graphRender = opengl('data');
            switch graphRender.HardwareSupportLevel
                case 'basic'; graphRenderSupport = 'hardwarebasic';
                case 'full';  graphRenderSupport = 'hardware';
                case 'none';  graphRenderSupport = 'software';
                otherwise;    graphRenderSupport = graphRender.HardwareSupportLevel; % "driverissue"
            end

            if ~ismember(graphRenderSupport, app.gpuType.Items)
                app.gpuType.Items{end+1} = graphRenderSupport;
            end
            app.gpuType.Value = graphRenderSupport;

            % Modo de operação:
            app.openAuxiliarAppAsDocked.Value   = app.mainApp.General.operationMode.Dock;
            app.openAuxiliarApp2Debug.Value     = app.mainApp.General.operationMode.Debug;
        end

        %-----------------------------------------------------------------%
        function editionFlag = File_checkEdition(app)
            editionFlag = false;

            if (app.mergeDataType.Value       ~= app.DefaultValues.File.DataType)          || ...
               (app.mergeAntenna.Value        ~= app.DefaultValues.File.Antenna)           || ...
               (abs(app.mergeDistance.Value - app.DefaultValues.File.Distance) > 1e-5)     || ...
               (app.channelManualMode.Value   ~= app.DefaultValues.File.ChannelManualMode) || ...
               (app.detectionManualMode.Value ~= app.DefaultValues.File.DetectionManualMode)

                editionFlag = true;
            end
        end

        %-----------------------------------------------------------------%
        function editionFlag = Graphics_checkEdition(app)
            editionFlag = false;

            if ~strcmp(app.imgFormat.Value,     app.DefaultValues.Graphics.Format) || ...
               ~strcmp(app.imgResolution.Value, app.DefaultValues.Graphics.Resolution)

                editionFlag = true;
            end
        end

        %-----------------------------------------------------------------%
        function Elevation_updatePanel(app)
            app.ElevationNPoints.Value     = num2str(app.mainApp.General.Elevation.Points);
            app.ElevationForceSearch.Value = app.mainApp.General.Elevation.ForceSearch;
            app.ElevationAPIServer.Value   = app.mainApp.General.Elevation.Server;

            if Elevation_checkEdition(app)
                app.general_ElevationRefresh.Visible = 1;
            else
                app.general_ElevationRefresh.Visible = 0;
            end
        end

        %-----------------------------------------------------------------%
        function editionFlag = Elevation_checkEdition(app)
            editionFlag = false;

            if ~strcmp(app.ElevationNPoints.Value, app.DefaultValues.Elevation.Points)     || ...
               (app.ElevationForceSearch.Value ~= app.DefaultValues.Elevation.ForceSearch) || ...
               ~strcmp(app.ElevationAPIServer.Value, app.DefaultValues.Elevation.Server)
                
                editionFlag = true;
            end
        end

        %-----------------------------------------------------------------%
        function Folder_updatePanel(app)
            % Na versão webapp, a configuração das pastas não é habilitada.

            if ~strcmp(app.mainApp.executionMode, 'webApp')
                app.btnFolder.Enable      = 1;

                DataHub_POST = app.mainApp.General.fileFolder.DataHub_POST;    
                if isfolder(DataHub_POST)
                    app.DataHubPOST.Value = DataHub_POST;
                end

                app.userPath.Value        = app.mainApp.General.fileFolder.userPath;
            end
        end

        %-----------------------------------------------------------------%
        function saveGeneralSettings(app)
            appUtil.generalSettingsSave(class.Constants.appName, app.rootFolder, app.mainApp.General_I, app.mainApp.executionMode)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            
            % A razão de ser deste app é possibilitar visualização/edição 
            % de algumas das informações do arquivo "GeneralSettings.json".
            app.mainApp    = mainApp;
            app.rootFolder = mainApp.rootFolder;

            if app.isDocked
                app.GridLayout.Padding(4) = 19;
                app.jsBackDoor = mainApp.jsBackDoor;
                startup_Controller(app)
            else
                appUtil.winPosition(app.UIFigure)
                startup_timerCreation(app)
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn')
            delete(app)
            
        end

        % Image clicked function: tool_RFDataHubButton
        function tool_RFDataHubButtonPushed(app, event)
            
            if isequal(app.mainApp.General.AppVersion.rfDataHub,  app.stableVersion.rfDataHub)
                app.tool_RFDataHubButton.Enable = 0;
                appUtil.modalWindow(app.UIFigure, 'warning', 'Módulo RFDataHub já atualizado!');
                return
            end

            d = appUtil.modalWindow(app.UIFigure, "progressdlg", 'Em andamento... esse processo pode demorar alguns minutos!');

            try
                appName = class.Constants.appName;
                rfDataHubLink = util.publicLink(appName, app.rootFolder, 'RFDataHub');
                model.RFDataHub.update(appName, app.rootFolder, app.mainApp.General.fileFolder.tempPath, rfDataHubLink)

                % Atualiza versão.
                global RFDataHub_info

                app.mainApp.General.AppVersion.rfDataHub = RFDataHub_info;
                app.stableVersion.rfDataHub = RFDataHub_info;
                app.tool_RFDataHubButton.Enable = 0;
                
            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            end

            General_updatePanel(app)
            delete(d)

        end

        % Selection changed function: LeftPanelRadioGroup
        function RadioButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.LeftPanelRadioGroup.SelectedObject;
            switch selectedButton
                case app.btnGeneral;  app.GridLayout.ColumnWidth(4:6) = {'1x',0,0};
                case app.btnAnalysis; app.GridLayout.ColumnWidth(4:6) = {0,'1x',0};
                case app.btnFolder;   app.GridLayout.ColumnWidth(4:6) = {0,0,'1x'};
            end
            
        end

        % Image clicked function: versionInfoRefresh
        function AppVersion_refreshButtonPushed(app, event)
            
            app.progressDialog.Visible = 'visible';

            [htmlContent, app.stableVersion, updatedModule] = util.HtmlTextGenerator.checkAvailableUpdate(app.mainApp.General, app.rootFolder);
            appUtil.modalWindow(app.UIFigure, "info", htmlContent);
            
            if ~ismember('RFDataHub', updatedModule)
                app.tool_RFDataHubButton.Enable = 1;
            end          

            app.progressDialog.Visible = 'hidden';

        end

        % Callback function: gpuType, graphics_Refresh, imgFormat, 
        % ...and 3 other components
        function Graphics_ParameterValueChanged(app, event)
            
            switch event.Source
                case app.gpuType
                    if ismember(app.gpuType.Value, {'software', 'hardware', 'hardwarebasic'})
                        eval(sprintf('opengl %s', app.gpuType.Value))

                        graphRender = opengl('data');
                        
                        app.mainApp.General.openGL = app.gpuType.Value;
                        app.mainApp.General.AppVersion.openGL = rmfield(graphRender, {'MaxTextureSize', 'Visual', 'SupportsGraphicsSmoothing', 'SupportsDepthPeelTransparency', 'SupportsAlignVertexCenters', 'Extensions', 'MaxFrameBufferSize'});
                    end

                case {app.imgFormat, app.imgResolution}
                    app.mainApp.General.Image = struct('Format', app.imgFormat.Value, 'Resolution', str2double(app.imgResolution.Value));

                case app.openAuxiliarAppAsDocked
                    app.mainApp.General.operationMode.Dock  = app.openAuxiliarAppAsDocked.Value;

                case app.openAuxiliarApp2Debug
                    app.mainApp.General.operationMode.Debug = app.openAuxiliarApp2Debug.Value;

                case app.graphics_Refresh
                    app.mainApp.General.Image = struct('Format', app.DefaultValues.Graphics.Format, 'Resolution', app.DefaultValues.Graphics.Resolution);
            end

            app.mainApp.General_I.openGL        = app.mainApp.General.openGL;
            app.mainApp.General_I.Image         = app.mainApp.General.Image;
            app.mainApp.General_I.operationMode = app.mainApp.General.operationMode;

            saveGeneralSettings(app)

        end

        % Callback function: ElevationAPIServer, ElevationForceSearch, 
        % ...and 2 other components
        function Elevation_ParameterValueChanged(app, event)

            switch event.Source
                case app.ElevationNPoints
                    app.mainApp.General.Elevation.Points      = str2double(app.ElevationNPoints.Value);

                case app.ElevationForceSearch
                    app.mainApp.General.Elevation.ForceSearch = app.ElevationForceSearch.Value;

                case app.ElevationAPIServer
                    app.mainApp.General.Elevation.Server      = app.ElevationAPIServer.Value;

                case app.general_ElevationRefresh
                    app.mainApp.General.Elevation = struct('Points',      str2double(app.DefaultValues.Elevation.Points), ...
                                                           'ForceSearch', app.DefaultValues.Elevation.ForceSearch,        ...
                                                           'Server',      app.DefaultValues.Elevation.Server);
            end

            app.mainApp.General_I.Elevation = app.mainApp.General.Elevation;
            saveGeneralSettings(app)
            Elevation_updatePanel(app)
            
        end

        % Image clicked function: DataHubPOSTButton, userPathButton
        function Folder_ButtonPushed(app, event)
            
            try
                relatedFolder = eval(sprintf('app.config_Folder_%s.Value', event.Source.Tag));                    
            catch
                relatedFolder = app.mainApp.General.fileFolder.(event.Source.Tag);
            end
            
            if isfolder(relatedFolder)
                initialFolder = relatedFolder;
            elseif isfile(relatedFolder)
                initialFolder = fileparts(relatedFolder);
            else
                initialFolder = app.userPath.Value;
            end
            
            selectedFolder = uigetdir(initialFolder);
            figure(app.UIFigure)

            if selectedFolder
                switch event.Source
                    case app.DataHubPOSTButton
                        if strcmp(app.mainApp.General.fileFolder.DataHub_POST, selectedFolder) 
                            return
                        else
                            selectedFolderFiles = dir(selectedFolder);
                            if ~ismember('.rfpreview_post', {selectedFolderFiles.name})
                                appUtil.modalWindow(app.UIFigure, 'error', 'Não se trata da pasta "DataHub - POST", do rfPreview.');
                                return
                            end

                            app.DataHubPOST.Value = selectedFolder;
                            app.mainApp.General.fileFolder.DataHub_POST = selectedFolder;
    
                            ipcMainMatlabCallsHandler(app.mainApp, app, 'checkDataHubLampStatus')
                        end

                    case app.userPathButton
                        app.userPath.Value = selectedFolder;
                        app.mainApp.General.fileFolder.userPath = selectedFolder;
                end

                app.mainApp.General_I.fileFolder = app.mainApp.General.fileFolder;
                saveGeneralSettings(app)
                Folder_updatePanel(app)
            end

        end

        % Image clicked function: openDevTools
        function openDevToolsClicked(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'openDevTools')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'rfPreview';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.toolGrid = uigridlayout(app.GridLayout);
            app.tool_RFDataHubButton = uiimage(app.toolGrid);
            app.tool_RFDataHubButton.ImageClickedFcn = createCallbackFcn(app, @tool_RFDataHubButtonPushed, true);
            app.tool_RFDataHubButton.Enable = 'off';
            app.tool_RFDataHubButton.Tooltip = {'Atualiza RFDataHub'};
            app.tool_RFDataHubButton.ImageSource = 'mosaic_32.png';
            app.openDevTools = uiimage(app.toolGrid);
            app.openDevTools.ImageClickedFcn = createCallbackFcn(app, @openDevToolsClicked, true);
            app.openDevTools.Tooltip = {'DevTools'};
            app.openDevTools.ImageSource = 'Debug_18.png';
            app.menu_ButtonGrid = uigridlayout(app.GridLayout);
            app.menu_ButtonLabel = uilabel(app.menu_ButtonGrid);
            app.menu_ButtonLabel.Text = 'CONFIGURAÇÕES';
            app.menu_ButtonIcon = uiimage(app.menu_ButtonGrid);
            app.menu_ButtonIcon.Tag = '1';
            app.menu_ButtonIcon.ImageSource = 'Settings_18.png';
            app.menuUnderline = uiimage(app.GridLayout);
            app.menuUnderline.ImageSource = 'LineH.svg';
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanelGrid = uigridlayout(app.LeftPanel);
            app.LeftPanelRadioGroup = uibuttongroup(app.GridLayout);
            app.LeftPanelRadioGroup.SelectionChangedFcn = createCallbackFcn(app, @RadioButtonGroupSelectionChanged, true);
            app.btnGeneral = uiradiobutton(app.LeftPanelRadioGroup);
            app.btnGeneral.Text = 'Aspectos gerais';
            app.btnGeneral.Value = true;
            app.btnAnalysis = uiradiobutton(app.LeftPanelRadioGroup);
            app.btnAnalysis.Text = 'Análise';
            app.btnFolder = uiradiobutton(app.LeftPanelRadioGroup);
            app.btnFolder.Enable = 'off';
            app.btnFolder.Text = 'Mapeamento de pastas';
            app.General_Grid = uigridlayout(app.GridLayout);
            app.versionInfoLabel = uilabel(app.General_Grid);
            app.versionInfoLabel.Text = 'ASPECTOS GERAIS';
            app.versionInfoRefresh = uiimage(app.General_Grid);
            app.versionInfoRefresh.ImageClickedFcn = createCallbackFcn(app, @AppVersion_refreshButtonPushed, true);
            app.versionInfoRefresh.Enable = 'off';
            app.versionInfoRefresh.Tooltip = {'Verifica atualizações'};
            app.versionInfoRefresh.ImageSource = 'Refresh_18.png';
            app.versionInfo = uilabel(app.General_Grid);
            app.versionInfo.Interpreter = 'html';
            app.versionInfo.Text = '';
            app.gpuTypeLabel = uilabel(app.General_Grid);
            app.gpuTypeLabel.Text = 'Unidade gráfica:';
            app.gpuType = uidropdown(app.General_Grid);
            app.gpuType.Items = {'hardwarebasic', 'hardware', 'software'};
            app.gpuType.ValueChangedFcn = createCallbackFcn(app, @Graphics_ParameterValueChanged, true);
            app.gpuType.Enable = 'off';
            app.gpuType.Value = 'hardware';
            app.openAuxiliarAppAsDocked = uicheckbox(app.General_Grid);
            app.openAuxiliarAppAsDocked.ValueChangedFcn = createCallbackFcn(app, @Graphics_ParameterValueChanged, true);
            app.openAuxiliarAppAsDocked.Enable = 'off';
            app.openAuxiliarAppAsDocked.Text = 'Modo DOCK: módulos auxiliares abertos na janela principal do app';
            app.openAuxiliarApp2Debug = uicheckbox(app.General_Grid);
            app.openAuxiliarApp2Debug.ValueChangedFcn = createCallbackFcn(app, @Graphics_ParameterValueChanged, true);
            app.openAuxiliarApp2Debug.Enable = 'off';
            app.openAuxiliarApp2Debug.Text = 'Modo DEBUG';
            app.CustomPlotGrid = uigridlayout(app.GridLayout);
            app.general_FileLabel = uilabel(app.CustomPlotGrid);
            app.general_FileLabel.Text = 'LEITURA DE ARQUIVOS';
            app.general_FilePanel = uipanel(app.CustomPlotGrid);
            app.general_GraphicsLabel = uilabel(app.CustomPlotGrid);
            app.general_GraphicsLabel.Text = 'GRÁFICO';
            app.general_GraphicsPanel = uipanel(app.CustomPlotGrid);
            app.general_GraphicsGrid = uigridlayout(app.general_GraphicsPanel);
            app.imgFormatLabel = uilabel(app.general_GraphicsGrid);
            app.imgFormatLabel.Text = 'Formato da imagem (RELATÓRIO):';
            app.imgFormat = uidropdown(app.general_GraphicsGrid);
            app.imgFormat.Items = {'jpeg', 'png'};
            app.imgFormat.ValueChangedFcn = createCallbackFcn(app, @Graphics_ParameterValueChanged, true);
            app.imgFormat.Value = 'jpeg';
            app.imgResolutionLabel = uilabel(app.general_GraphicsGrid);
            app.imgResolutionLabel.Text = 'Resolução (dpi):';
            app.imgResolution = uidropdown(app.general_GraphicsGrid);
            app.imgResolution.Items = {'100', '120', '150', '200'};
            app.imgResolution.ValueChangedFcn = createCallbackFcn(app, @Graphics_ParameterValueChanged, true);
            app.imgResolution.Value = '100';
            app.general_ElevationLabel = uilabel(app.CustomPlotGrid);
            app.general_ElevationLabel.Text = 'ELEVAÇÃO';
            app.general_ElevationRefresh = uiimage(app.CustomPlotGrid);
            app.general_ElevationRefresh.ImageClickedFcn = createCallbackFcn(app, @Elevation_ParameterValueChanged, true);
            app.general_ElevationRefresh.Visible = 'off';
            app.general_ElevationRefresh.ImageSource = 'Refresh_18.png';
            app.general_ElevationPanel = uipanel(app.CustomPlotGrid);
            app.general_ElevationGrid = uigridlayout(app.general_ElevationPanel);
            app.ElevationAPIServerLabel = uilabel(app.general_ElevationGrid);
            app.ElevationAPIServerLabel.Text = 'Fonte:';
            app.ElevationAPIServer = uidropdown(app.general_ElevationGrid);
            app.ElevationAPIServer.Items = {'Open-Elevation', 'MathWorks WMS Server'};
            app.ElevationAPIServer.ValueChangedFcn = createCallbackFcn(app, @Elevation_ParameterValueChanged, true);
            app.ElevationAPIServer.Value = 'MathWorks WMS Server';
            app.ElevationNPointsLabel = uilabel(app.general_ElevationGrid);
            app.ElevationNPointsLabel.Text = 'Pontos enlace:';
            app.ElevationNPoints = uidropdown(app.general_ElevationGrid);
            app.ElevationNPoints.Items = {'64', '128', '256', '512', '1024'};
            app.ElevationNPoints.ValueChangedFcn = createCallbackFcn(app, @Elevation_ParameterValueChanged, true);
            app.ElevationNPoints.Value = '256';
            app.ElevationForceSearch = uicheckbox(app.general_ElevationGrid);
            app.ElevationForceSearch.ValueChangedFcn = createCallbackFcn(app, @Elevation_ParameterValueChanged, true);
            app.ElevationForceSearch.Text = 'Força consulta ao servidor (ignorando eventual informação em cache).';
            app.graphics_Refresh = uiimage(app.CustomPlotGrid);
            app.graphics_Refresh.ImageClickedFcn = createCallbackFcn(app, @Graphics_ParameterValueChanged, true);
            app.graphics_Refresh.Visible = 'off';
            app.graphics_Refresh.Tooltip = {'Volta à configuração inicial'};
            app.graphics_Refresh.ImageSource = 'Refresh_18.png';
            app.Folders_Grid = uigridlayout(app.GridLayout);
            app.config_FolderMapLabel = uilabel(app.Folders_Grid);
            app.config_FolderMapLabel.Text = 'MAPEAMENTO DE PASTAS';
            app.FolderMapPanel = uipanel(app.Folders_Grid);
            app.FolderMapGrid = uigridlayout(app.FolderMapPanel);
            app.DataHubPOSTLabel = uilabel(app.FolderMapGrid);
            app.DataHubPOSTLabel.Text = 'DataHub - POST:';
            app.DataHubPOST = uieditfield(app.FolderMapGrid, 'text');
            app.DataHubPOST.Editable = 'off';
            app.DataHubPOSTButton = uiimage(app.FolderMapGrid);
            app.DataHubPOSTButton.ImageClickedFcn = createCallbackFcn(app, @Folder_ButtonPushed, true);
            app.DataHubPOSTButton.Tag = 'DataHub_POST';
            app.DataHubPOSTButton.ImageSource = 'OpenFile_36x36.png';
            app.userPathLabel = uilabel(app.FolderMapGrid);
            app.userPathLabel.Text = 'Pasta do usuário:';
            app.userPath = uieditfield(app.FolderMapGrid, 'text');
            app.userPath.Editable = 'off';
            app.userPathButton = uiimage(app.FolderMapGrid);
            app.userPathButton.ImageClickedFcn = createCallbackFcn(app, @Folder_ButtonPushed, true);
            app.userPathButton.Tag = 'userPath';
            app.userPathButton.ImageSource = 'OpenFile_36x36.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winConfig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end