classdef winPropagation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        baseMapIdx                     matlab.ui.control.NumericEditField
        GridLayout                     matlab.ui.container.GridLayout
        axesToolbarGrid                matlab.ui.container.GridLayout
        axesTool_ChangeMapLayer        matlab.ui.control.Image
        axesTool_RegionZoom            matlab.ui.control.Image
        axesTool_RestoreView           matlab.ui.control.Image
        toolGrid                       matlab.ui.container.GridLayout
        LimparButton                   matlab.ui.control.Button
        SimularButton                  matlab.ui.control.Button
        tool_tableNRowsIcon            matlab.ui.control.Image
        tool_tableNRows                matlab.ui.control.Label
        tool_ExportButton              matlab.ui.control.Image
        tool_Separator                 matlab.ui.control.Image
        tool_PDFButton                 matlab.ui.control.Image
        tool_RFLinkButton              matlab.ui.control.Image
        tool_TableVisibility           matlab.ui.control.Image
        tool_ControlPanelVisibility    matlab.ui.control.Image
        UITable                        matlab.ui.control.Table
        chReportUndock                 matlab.ui.control.Image
        chReportHotDownload            matlab.ui.control.Image
        chReportDownloadTime           matlab.ui.control.Label
        chReportHTML                   matlab.ui.control.HTML
        plotPanel                      matlab.ui.container.Panel
        panelGrid                      matlab.ui.container.GridLayout
        menu_MainGrid                  matlab.ui.container.GridLayout
        menu_Button4Grid               matlab.ui.container.GridLayout
        menu_Button4Icon               matlab.ui.control.Image
        menu_Button4Label              matlab.ui.control.Label
        menu_Button3Grid               matlab.ui.container.GridLayout
        menu_Button3Icon               matlab.ui.control.Image
        menu_Button3Label              matlab.ui.control.Label
        menu_Button2Grid               matlab.ui.container.GridLayout
        menu_Button2Icon               matlab.ui.control.Image
        menu_Button2Label              matlab.ui.control.Label
        menu_Button1Grid               matlab.ui.container.GridLayout
        menu_Button1Icon               matlab.ui.control.Image
        menu_Button1Label              matlab.ui.control.Label
        menuUnderline                  matlab.ui.control.Image
        ControlTabGroup                matlab.ui.container.TabGroup
        Tab_1                          matlab.ui.container.Tab
        Tab1_Grid                      matlab.ui.container.GridLayout
        stationInfo                    matlab.ui.control.Label
        stationInfoAntennaPattern      matlab.ui.container.Panel
        stationInfoImage               matlab.ui.control.Image
        referenceTX_Panel              matlab.ui.container.Panel
        referenceTX_Grid               matlab.ui.container.GridLayout
        referenceTX_Height             matlab.ui.control.NumericEditField
        referenceTX_HeightLabel        matlab.ui.control.Label
        referenceTX_Longitude          matlab.ui.control.NumericEditField
        referenceTX_LongitudeLabel     matlab.ui.control.Label
        referenceTX_Latitude           matlab.ui.control.NumericEditField
        referenceTX_LatitudeLabel      matlab.ui.control.Label
        referenceTV_TitleGrid          matlab.ui.container.GridLayout
        referenceTX_EditionCancel      matlab.ui.control.Image
        referenceTX_EditionConfirm     matlab.ui.control.Image
        referenceTX_EditionMode        matlab.ui.control.Image
        referenceTX_Refresh            matlab.ui.control.Image
        referenceTX_Label              matlab.ui.control.Label
        referenceTX_Icon               matlab.ui.control.Image
        Tab_2                          matlab.ui.container.Tab
        Tab2_Grid                      matlab.ui.container.GridLayout
        filter_Tree                    matlab.ui.container.CheckBoxTree
        filter_AddImage                matlab.ui.control.Image
        filter_SecondaryValuePanel     matlab.ui.container.ButtonGroup
        filter_SecondaryValueSubpanel  matlab.ui.container.Panel
        filter_SecondaryValueGrid      matlab.ui.container.GridLayout
        filter_SecondaryLogicalOperator  matlab.ui.control.DropDown
        filter_SecondaryLogicalOperatorLabel  matlab.ui.control.Label
        filter_SecondaryReferenceFilter  matlab.ui.control.DropDown
        filter_SecondaryReferenceFilterLabel  matlab.ui.control.Label
        filter_SecondaryTextList       matlab.ui.control.DropDown
        filter_SecondaryTextFree       matlab.ui.control.EditField
        filter_SecondaryNumValue2      matlab.ui.control.NumericEditField
        filter_SecondaryNumSeparator   matlab.ui.control.Label
        filter_SecondaryNumValue1      matlab.ui.control.NumericEditField
        filter_SecondaryOperation10    matlab.ui.control.ToggleButton
        filter_SecondaryOperation9     matlab.ui.control.ToggleButton
        filter_SecondaryOperation8     matlab.ui.control.ToggleButton
        filter_SecondaryOperation7     matlab.ui.control.ToggleButton
        filter_SecondaryOperation6     matlab.ui.control.ToggleButton
        filter_SecondaryOperation5     matlab.ui.control.ToggleButton
        filter_SecondaryOperation4     matlab.ui.control.ToggleButton
        filter_SecondaryOperation3     matlab.ui.control.ToggleButton
        filter_SecondaryOperation2     matlab.ui.control.ToggleButton
        filter_SecondaryOperation1     matlab.ui.control.ToggleButton
        filter_SecondaryTypePanel      matlab.ui.container.ButtonGroup
        filter_SecondaryType13         matlab.ui.control.RadioButton
        filter_SecondaryType12         matlab.ui.control.RadioButton
        filter_SecondaryType11         matlab.ui.control.RadioButton
        filter_SecondaryType10         matlab.ui.control.RadioButton
        filter_SecondaryType9          matlab.ui.control.RadioButton
        filter_SecondaryType8          matlab.ui.control.RadioButton
        filter_SecondaryType7          matlab.ui.control.RadioButton
        filter_SecondaryType6          matlab.ui.control.RadioButton
        filter_SecondaryType5          matlab.ui.control.RadioButton
        filter_SecondaryType4          matlab.ui.control.RadioButton
        filter_SecondaryType3          matlab.ui.control.RadioButton
        filter_SecondaryType2          matlab.ui.control.RadioButton
        filter_SecondaryType1          matlab.ui.control.RadioButton
        filter_SecondaryLabel          matlab.ui.control.Label
        referenceRX_Panel              matlab.ui.container.Panel
        referenceRX_Grid               matlab.ui.container.GridLayout
        referenceRX_Height             matlab.ui.control.NumericEditField
        referenceRX_HeightLabel        matlab.ui.control.Label
        referenceRX_Longitude          matlab.ui.control.NumericEditField
        referenceRX_LongitudeLabel     matlab.ui.control.Label
        referenceRX_Latitude           matlab.ui.control.NumericEditField
        referenceRX_LatitudeLabel      matlab.ui.control.Label
        referenceRX_EditionCancel      matlab.ui.control.Image
        referenceRX_EditionConfirm     matlab.ui.control.Image
        referenceRX_EditionMode        matlab.ui.control.Image
        referenceRX_Refresh            matlab.ui.control.Image
        referenceRX_Label              matlab.ui.control.Label
        referenceRX_Icon               matlab.ui.control.Image
        Tab_3                          matlab.ui.container.Tab
        Tab3_Grid                      matlab.ui.container.GridLayout
        misc_ElevationSourcePanel      matlab.ui.container.Panel
        misc_ElevationSourceGrid       matlab.ui.container.GridLayout
        misc_ElevationForceSearch      matlab.ui.control.CheckBox
        misc_ElevationNPoints          matlab.ui.control.DropDown
        misc_ElevationNPointsLabel     matlab.ui.control.Label
        misc_ElevationAPISource        matlab.ui.control.DropDown
        misc_ElevationAPISourceLabel   matlab.ui.control.Label
        misc_ElevationSourceLabel      matlab.ui.control.Label
        config_geoAxesPanel            matlab.ui.container.Panel
        config_geoAxesGrid             matlab.ui.container.GridLayout
        config_RX_Size                 matlab.ui.control.Slider
        config_RX_Color                matlab.ui.control.ColorPicker
        config_RX_Label                matlab.ui.control.Label
        config_TX_DataTipVisibility    matlab.ui.control.DropDown
        config_TX_Size                 matlab.ui.control.Slider
        config_TX_Color                matlab.ui.control.ColorPicker
        config_TX_Label                matlab.ui.control.Label
        config_Station_Size            matlab.ui.control.Slider
        config_Station_Color           matlab.ui.control.ColorPicker
        config_Station_Label           matlab.ui.control.Label
        config_geoAxesSubPanel         matlab.ui.container.Panel
        config_geoAxesSubGrid          matlab.ui.container.GridLayout
        config_Colormap                matlab.ui.control.DropDown
        config_ColormapLabel           matlab.ui.control.Label
        config_Basemap                 matlab.ui.control.DropDown
        config_BasemapLabel            matlab.ui.control.Label
        config_geoAxesSublabel         matlab.ui.control.Label
        config_Refresh                 matlab.ui.control.Image
        config_geoAxesLabel            matlab.ui.control.Label
        Tab_4                          matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        Panel_3                        matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        Panel_4                        matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        txAntenna                      matlab.ui.control.EditField
        txAntennaButton                matlab.ui.control.Image
        txAntennaLabel                 matlab.ui.control.Label
        AzimuteEditField               matlab.ui.control.NumericEditField
        AzimuteEditFieldLabel          matlab.ui.control.Label
        TiltEditField                  matlab.ui.control.NumericEditField
        TiltEditFieldLabel             matlab.ui.control.Label
        AlturaEditField_2              matlab.ui.control.NumericEditField
        AlturaEditField_2Label         matlab.ui.control.Label
        AntenaLabel                    matlab.ui.control.Label
        LongitudeEditField             matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel        matlab.ui.control.Label
        LatitudeEditField              matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel         matlab.ui.control.Label
        PotnciaWattsEditField          matlab.ui.control.NumericEditField
        PotnciaWattsEditFieldLabel     matlab.ui.control.Label
        FrequnciaMHzEditField          matlab.ui.control.NumericEditField
        FrequnciaMHzEditFieldLabel     matlab.ui.control.Label
        IdentificaoEditField           matlab.ui.control.EditField
        IdentificaoEditFieldLabel      matlab.ui.control.Label
        EstaoEditField                 matlab.ui.control.EditField
        EstaoEditFieldLabel            matlab.ui.control.Label
        EstaotransmissoraLabel         matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        rxLatitudeEditField            matlab.ui.control.NumericEditField
        rxLongitudeEditFieldLabel      matlab.ui.control.Label
        rxLongitudeEditField           matlab.ui.control.NumericEditField
        rxLatitudeEditFieldLabel       matlab.ui.control.Label
        ArquivoantenaEditField         matlab.ui.control.EditField
        rxAntennaButton                matlab.ui.control.Image
        ArquivoantenaEditFieldLabel    matlab.ui.control.Label
        AlturaEditField                matlab.ui.control.NumericEditField
        AlturaEditFieldLabel           matlab.ui.control.Label
        EstaoreceptoraLabel            matlab.ui.control.Label
        ClutterEditField               matlab.ui.control.EditField
        clutterButton                  matlab.ui.control.Image
        ClutterEditFieldLabel          matlab.ui.control.Label
        prop_terrain                   matlab.ui.control.EditField
        prop_terrainButton             matlab.ui.control.Image
        prop_terrainLabel              matlab.ui.control.Label
        RaiometrosEditField            matlab.ui.control.NumericEditField
        RaiometrosEditFieldLabel       matlab.ui.control.Label
        prop_Model                     matlab.ui.control.DropDown
        prop_ModelLabel                matlab.ui.control.Label
        filter_ContextMenu             matlab.ui.container.ContextMenu
        filter_delButton               matlab.ui.container.Menu
        filter_delAllButton            matlab.ui.container.Menu
        ContextMenu                    matlab.ui.container.ContextMenu
        PerfilMenu                     matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false

        mainApp
        General
        rootFolder

        % A função do timer é executada uma única vez após a renderização
        % da figura, lendo arquivos de configuração, iniciando modo de operação
        % paralelo etc. A ideia é deixar o MATLAB focar apenas na criação dos 
        % componentes essenciais da GUI (especificados em "createComponents"), 
        % mostrando a GUI para o usuário o mais rápido possível.
        timerObj
        jsBackDoor

        % Janela de progresso já criada no DOM. Dessa forma, controla-se 
        % apenas a sua visibilidade - e tornando desnecessário criá-la a
        % cada chamada (usando uiprogressdlg, por exemplo).
        progressDialog

        %-----------------------------------------------------------------%
        % ESPECIFICIDADES AUXAPP.WINRFDATAHUB
        %-----------------------------------------------------------------%
        rfDataHub
        rfDataHubLOG
        rfDataHubSummary
        rfDataHubAnnotation = table(string.empty, int32([]), struct('Latitude', {}, 'Longitude', {}, 'AntennaHeight', {}), 'VariableNames', {'ID', 'Station', 'TXSite'})
        
        UIAxes1
        UIAxes2
        UIAxes3
        restoreView    = struct('ID', {}, 'xLim', {}, 'yLim', {}, 'cLim', {})

        elevationObj   = RF.Elevation
        ChannelReportObj

        filterTable    = table('Size',          [0, 9],                                                                      ...
                               'VariableTypes', {'cell', 'int8', 'int8', 'cell', 'cell', 'int8', 'cell', 'logical', 'cell'}, ...
                               'VariableNames', {'Order', 'ID', 'RelatedID', 'Type', 'Operation', 'Column', 'Value', 'Enable', 'uuid'})
    end


    methods
        function ipcSecundaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        startup_Controller(app)
                    case 'auxApp.winRFDataHub.filter_Tree'
                        filter_delFilter(app, struct('Source', app.filter_delButton))
                    otherwise
                        error('UnexpectedEvent')
                end

            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            end
        end
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        % INICIALIZAÇÃO
        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor = uihtml(app.UIFigure, "HTMLSource",           appUtil.jsBackDoorHTMLSource(),                 ...
                                                  "HTMLEventReceivedFcn", @(~, evt)ipcSecundaryJSEventsHandler(app, evt), ...
                                                  "Visible",              "off");
        end

        %-------------------------------------------------------------------------%
        function jsBackDoor_Customizations(app, tabIndex)
            persistent customizationStatus
            if isempty(customizationStatus)
                customizationStatus = [false, false, false, false];
            end

            switch tabIndex
                case 0 % STARTUP
                    if app.isDocked
                        app.progressDialog = app.mainApp.progressDialog;
                    else
                        sendEventToHTMLSource(app.jsBackDoor, 'startup', app.mainApp.executionMode);
                        app.progressDialog = ccTools.ProgressDialog(app.jsBackDoor);                        
                    end
                    customizationStatus = [false, false, false, false];

                otherwise
                    if customizationStatus(tabIndex)
                        return
                    end

                    appName = class(app);

                    customizationStatus(tabIndex) = true;
                    switch tabIndex
                        case 1 % RFDATAHUB
                            elToModify = {app.ControlTabGroup, app.axesToolbarGrid, app.stationInfo, app.stationInfoImage};
                            elDataTag  = ui.CustomizationBase.getElementsDataTag(elToModify);
                            if ~isempty(elDataTag)
                                sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', {                                                                          ...
                                    struct('appName', appName, 'dataTag', elDataTag{1}, 'generation', 0, 'style', struct('border', 'none', 'backgroundColor', 'transparent')),               ...
                                    struct('appName', appName, 'dataTag', elDataTag{2}, 'generation', 0, 'style', struct('borderBottomLeftRadius', '5px', 'borderBottomRightRadius', '5px')) ...
                                });

                                ui.TextView.startup(app.jsBackDoor, elToModify{3}, appName);
                                ui.TextView.startup(app.jsBackDoor, elToModify{4}, appName, 'NÃO HÁ REGISTRO QUE ATENDA<br>AOS CRITÉRIOS DE FILTRAGEM');
                            end

                        case 2 % FILTRAGEM
                            elToModify = {app.filter_Tree};
                            elDataTag  = ui.CustomizationBase.getElementsDataTag(elToModify);
                            if ~isempty(elDataTag)
                                sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', {                                                                          ...
                                    struct('appName', appName, 'dataTag', elDataTag{1}, 'listener', struct('componentName', 'auxApp.winRFDataHub.filter_Tree', 'keyEvents', {{'Delete', 'Backspace'}})) ...
                                });
                            end

                            filter_TreeBuilding(app)
                            
                        case 3 % CONFIGURAÇÕES GERAIS
                            % ...

                        case 4 % ABA DE TESTES (PREDIÇÃO)
                            % ...
                    end
            end
        end

        %-----------------------------------------------------------------%
        function startup_timerCreation(app)            
            % A criação desse timer tem como objetivo garantir uma renderização 
            % mais rápida dos componentes principais da GUI, possibilitando a 
            % visualização da sua tela inicialpelo usuário. Trata-se de aspecto 
            % essencial quando o app é compilado como webapp.

            app.timerObj = timer("ExecutionMode", "fixedSpacing", ...
                                 "StartDelay",    1.5,            ...
                                 "Period",        .1,             ...
                                 "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj)
        end

        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj)
                delete(app.timerObj)
                
                jsBackDoor_Initialization(app)
            end
        end

        %-----------------------------------------------------------------%
        function startup_Controller(app)
            drawnow
            jsBackDoor_Customizations(app, 0)
            jsBackDoor_Customizations(app, 1)

            app.progressDialog.Visible = 'visible';
            
            startup_AppProperties(app)
            startup_AxesCreation(app)
            startup_GUIComponents(app)
            filter_TableFiltering(app)            
            
            app.progressDialog.Visible = 'hidden';
        end

        %-----------------------------------------------------------------%
        function startup_AppProperties(app)
            % refRX: armazena o valor inicial da estação receptora de referência
            %        para fins de análise da edição.
            rxSite = referenceRX_InitialValue(app);
            app.referenceRX_Refresh.UserData.InitialValue = rxSite;
            referenceRX_UpdatePanel(app, rxSite)

            % app.rfDataHub
            global RFDataHub
            global RFDataHubLog
            
            app.rfDataHub = RFDataHub;
            app.rfDataHubLOG = RFDataHubLog;
            
            % Contorna erro da função inROI, que retorna como se todos os
            % pontos estivessem internos ao ROI, quando as coordenadas
            % estão em float32. No float64 isso não acontece... aberto BUG
            % na Mathworks, que indicou estar ciente.
            app.rfDataHub.Latitude    = double(app.rfDataHub.Latitude);
            app.rfDataHub.Longitude   = double(app.rfDataHub.Longitude);

            app.rfDataHub.ID          = "#" + string((1:height(RFDataHub))');
            app.rfDataHub.Description = "[" + string(RFDataHub.Source) + "] " + string(RFDataHub.Status) + ", " + string(RFDataHub.StationClass) + ", " + string(RFDataHub.Name) + ", " + string(RFDataHub.Location) + "/" + string(RFDataHub.State) + " (M=" + string(RFDataHub.MergeCount) + ")";
            referenceRX_CalculateDistance(app, rxSite)
                        
            % app.rfDataHubSummary
            app.rfDataHubSummary = summary(RFDataHub);

            % A coluna "Source" possui agrupamentos da fonte dos dados,
            % decorrente da mesclagem de estações.
            tempSourceList = cellfun(@(x) strsplit(x, ' | '), app.rfDataHubSummary.Source.Categories, 'UniformOutput', false);
            app.rfDataHubSummary.Source.RawCategories = unique(horzcat(tempSourceList{:}))';

            % A coluna "Location" não está sendo corretamente ordenada por
            % conta dos caracteres especiais.
            tempLocationList = textAnalysis.preProcessedData(app.rfDataHubSummary.Location.Categories);
            [app.rfDataHubSummary.Location.CacheCategories, idxSort] = sort(tempLocationList);
            app.rfDataHubSummary.Location.Categories = app.rfDataHubSummary.Location.Categories(idxSort);

            % lastPrimarySearch
            filter_getReferenceSearch(app)
        end

        %-----------------------------------------------------------------%
        function startup_AxesCreation(app)
            hParent     = tiledlayout(app.plotPanel, 2, 2, "Padding", "none", "TileSpacing", "none");

            % Eixo geográfico: MAPA
            app.UIAxes1 = plot.axes.Creation(hParent, 'Geographic', {'Basemap', app.config_Basemap.Value,                 ...
                                                                     'Color',    [.2, .2, .2], 'GridColor', [.5, .5, .5], ...
                                                                     'UserData', struct('CLimMode', 'auto', 'Colormap', '')});

            app.UIAxes1.Layout.Tile = 1;
            app.UIAxes1.Layout.TileSpan = [2, 2];

            set(app.UIAxes1.LatitudeAxis,  'TickLabels', {}, 'Color', 'none')
            set(app.UIAxes1.LongitudeAxis, 'TickLabels', {}, 'Color', 'none')
            geolimits(app.UIAxes1, 'auto')
            plot.axes.Colormap(app.UIAxes1, app.config_Colormap.Value)

            if ismember(app.config_Basemap.Value, {'darkwater', 'none'})
                app.UIAxes1.Grid = 'on';
            end

            app.UIAxes1.ContextMenu = app.ContextMenu;

            % Eixo cartesiano: PERFIL DE RELEVO
            % app.UIAxes2 = plot.axes.Creation(hParent, 'Cartesian', {'XGrid', 'off', 'XMinorGrid', 'off', 'XTick', [], 'XColor', [.8,.8,.8], 'XLimitMethod', 'padded', ...
            %                                                         'YGrid', 'off', 'YMinorGrid', 'off', 'YTick', [], 'YColor', 'none',                               ...
            %                                                         'Color', 'none', 'Clipping', 'off', 'LineWidth', 2, 'Layer', 'top', 'Visible', 'off'});
                        app.UIAxes2 = plot.axes.Creation(hParent, 'Cartesian', {'XColor', [.8,.8,.8], 'XLimitMethod', 'padded', 'Color', 'none', 'XMinorGrid', 'off',  'YMinorGrid', 'off',...
                                                                                           'Clipping', 'off', 'LineWidth', 2, 'Layer', 'top', 'Visible', 'off'});
            app.UIAxes2.Layout.Tile = 3;
            app.UIAxes2.Layout.TileSpan = [1 2];
            app.UIAxes2.XAxis.TickLabelFormat = '%.1f';

            % Eixo cartesiano: DIAGRAMA DE RADIAÇÃO DA ANTENA
            app.UIAxes3 = polaraxes(app.stationInfoAntennaPattern, 'Units',             'normalized',      ...
                                                                   'Position',          [.08, 0, .9, .85], ...
                                                                   'ThetaZeroLocation', 'top',             ...
                                                                   'Toolbar',           [],                ...
                                                                   'FontSize',          8,                 ...
                                                                   'Color',             'yellow',          ...
                                                                   'ThetaTick',         0,                 ...
                                                                   'ThetaDir',          'clockwise',       ...
                                                                   'RTickLabel',        {});
            hold(app.UIAxes3, 'on')

            % Legenda
            legend(app.UIAxes1, 'Location', 'southwest', 'Color', [.94,.94,.94], 'EdgeColor', [.9,.9,.9], 'NumColumns', 4, 'LineWidth', .5, 'FontSize', 7.5)

            % Axes interactions:
            plot.axes.Interactivity.DefaultCreation(app.UIAxes1, [dataTipInteraction, zoomInteraction, panInteraction])
            plot.axes.Interactivity.DefaultCreation(app.UIAxes2, dataTipInteraction)
        end

        %-----------------------------------------------------------------%
        function startup_GUIComponents(app)
            % Controles de funcionalidades:
            app.referenceTX_EditionMode.UserData = false;
            app.referenceRX_EditionMode.UserData = false;
            app.tool_TableVisibility.UserData    = 1;
            app.tool_RFLinkButton.UserData       = false;
            app.tool_PDFButton.UserData          = false;            

            % Elevação:
            app.misc_ElevationAPISource.Value    = app.General.Elevation.Server;
            app.misc_ElevationNPoints.Value      = num2str(app.General.Elevation.Points);
            app.misc_ElevationForceSearch.Value  = app.General.Elevation.ForceSearch;

            % Aba de testes de Predição:
            app.prop_Model.Items = {app.General.propagation.model.name};
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function [idxRFDataHub, idxSelectedRow] = getRFDataHubIndex(app)
            if isempty(app.UITable.Selection)
                idxRFDataHub   = [];
                idxSelectedRow = 0;
                
            else
                idxSelectedRow = app.UITable.Selection(1);
                idxVirtual     = app.UITable.Data.ID(idxSelectedRow);    
                idxRFDataHub   = str2double(extractAfter(idxVirtual, '#'));
            end
        end

        %-----------------------------------------------------------------%
        function varargout = RFLinkObjects(app, siteType, varargin)
            arguments
                app 
                siteType char {mustBeMember(siteType, {'TX', 'RX', 'TX-RX'})} = 'TX-RX'
            end

            arguments (Repeating)
                varargin
            end
            % txSite e rxSite estão como struct, mas basta mudar para "txsite" e 
            % "rxsite" que eles poderão ser usados em predições, uma vez que os 
            % campos da estrutura são idênticos às propriedades dos objetos.

            varargout = {};

            if contains(siteType, 'TX')
                idxRFDataHub = varargin{1};

                % TX
                txSite = struct('Name',                 'TX',                                                 ...
                                'TransmitterFrequency', double(app.rfDataHub.Frequency(idxRFDataHub)) * 1e+6, ...
                                'Latitude',             app.referenceTX_Latitude.Value,                       ...
                                'Longitude',            app.referenceTX_Longitude.Value,                      ...
                                'AntennaHeight',        app.referenceTX_Height.Value,                         ...
                                'ID',                   app.rfDataHub.ID(idxRFDataHub),                       ...
                                'Station',              app.rfDataHub.Station(idxRFDataHub));
                varargout{end+1} = txSite;
            end

            if contains(siteType, 'RX')
                % RX
                rxSite = struct('Name',                 'RX',                            ...
                                'Latitude',             app.referenceRX_Latitude.Value,  ...
                                'Longitude',            app.referenceRX_Longitude.Value, ...
                                'AntennaHeight',        app.referenceRX_Height.Value);
                varargout{end+1} = rxSite;
            end
        end


        %-----------------------------------------------------------------%
        % TAB: 1
        % PAINEL: ESTAÇÃO TRANSMISSORA - TX
        %-----------------------------------------------------------------%
        function referenceTX_UpdatePanel(app, idxRFDataHub)
            idxAnnotation = referenceTX_AddOrDelTXSiteTempList(app, 'Search', app.rfDataHub.ID(idxRFDataHub));

            % A ideia aqui é destacar as informações que foram editadas...
            txLatitudeFontColor   = [0,0,0];
            txLongitudeFontColor  = [0,0,0];
            txHeightFontColor     = [0,0,0];

            txLatitudeBackground  = [1,1,1];
            txLongitudeBackground = [1,1,1];
            txHeightBackground    = [1,1,1];

            if idxAnnotation
                app.referenceTX_Refresh.Visible = 1;
                % Latitude
                if app.rfDataHubAnnotation.TXSite(idxAnnotation).Latitude.Status
                    txLatitude  = app.rfDataHubAnnotation.TXSite(idxAnnotation).Latitude.EditedValue;
                    txLatitudeFontColor   = [1,1,1];
                    txLatitudeBackground  = [1,0,0];
                else
                    txLatitude  = app.rfDataHubAnnotation.TXSite(idxAnnotation).Latitude.RawValue;
                end

                % Longitude
                if app.rfDataHubAnnotation.TXSite(idxAnnotation).Longitude.Status
                    txLongitude = app.rfDataHubAnnotation.TXSite(idxAnnotation).Longitude.EditedValue;
                    txLongitudeFontColor  = [1,1,1];
                    txLongitudeBackground = [1,0,0];
                else
                    txLongitude = app.rfDataHubAnnotation.TXSite(idxAnnotation).Longitude.RawValue;
                end

                % Height
                if app.rfDataHubAnnotation.TXSite(idxAnnotation).AntennaHeight.Status
                    txHeight    = app.rfDataHubAnnotation.TXSite(idxAnnotation).AntennaHeight.EditedValue;
                    txHeightFontColor     = [1,1,1];
                    txHeightBackground    = [1,0,0];
                else
                    txHeight    = app.rfDataHubAnnotation.TXSite(idxAnnotation).AntennaHeight.RawValue;
                end

            else
                app.referenceTX_Refresh.Visible = 0;

                [txLatitude,  ...
                 txLongitude, ...
                 txHeight]  = referenceTX_getRawData(app);
            end

            set(app.referenceTX_Latitude,  'Value', txLatitude,  'FontColor', txLatitudeFontColor,  'BackgroundColor', txLatitudeBackground)
            set(app.referenceTX_Longitude, 'Value', txLongitude, 'FontColor', txLongitudeFontColor, 'BackgroundColor', txLongitudeBackground)
            set(app.referenceTX_Height,    'Value', txHeight,    'FontColor', txHeightFontColor,    'BackgroundColor', txHeightBackground)
        end

        %-----------------------------------------------------------------%
        function referenceTX_EditionPanelLayout(app, editionStatus)
            arguments
                app 
                editionStatus char {mustBeMember(editionStatus, {'on', 'off'})}
            end

            idxRFDataHub = getRFDataHubIndex(app);
            hEditFields = findobj(app.referenceTX_Grid.Children, '-not', 'Type', 'uilabel');            

            switch editionStatus
                case 'on'
                    set(app.referenceTX_EditionMode, 'ImageSource', 'Edit_32Filled.png', 'UserData', true)
                    set(hEditFields, 'Editable', true)
                    
                    app.referenceTV_TitleGrid.ColumnWidth(end-1:end) = {16,16};
                    app.referenceTX_EditionConfirm.Enable = 1;
                    app.referenceTX_EditionCancel.Enable  = 1;

                case 'off'
                    referenceTX_UpdatePanel(app, idxRFDataHub)

                    set(app.referenceTX_EditionMode, 'ImageSource', 'Edit_32.png', 'UserData', false)
                    set(hEditFields, 'Editable', false)

                    app.referenceTV_TitleGrid.ColumnWidth(end-1:end) = {0,0};
                    app.referenceTX_EditionConfirm.Enable = 0;
                    app.referenceTX_EditionCancel.Enable  = 0;
            end
        end

        %-----------------------------------------------------------------%
        function varargout = referenceTX_AddOrDelTXSiteTempList(app, operationType, varargin)
            arguments
                app 
                operationType char {mustBeMember(operationType, {'Search', 'Add', 'Del'})}
            end

            arguments (Repeating)
                varargin
            end

            switch operationType
                case 'Search'
                    ID = varargin{1};
                    [~, idxAnnotation] = ismember(ID, app.rfDataHubAnnotation.ID);
                    varargout = {idxAnnotation};

                case 'Add'
                    [txObj, ID, Station, idxAnnotation] = referenceTX_checkTXSiteTempList(app);

                    [txLatitude,  ...
                     txLongitude, ...
                     txHeight]  = referenceTX_getRawData(app);

                    % Confirma que ocorreu alteração em algum dos parâmetros 
                    % do registro.
                    txSiteDiff = struct('Latitude',      struct('Status', false, 'RawValue', txLatitude,  'EditedValue', []), ...
                                        'Longitude',     struct('Status', false, 'RawValue', txLongitude, 'EditedValue', []), ...
                                        'AntennaHeight', struct('Status', false, 'RawValue', txHeight,    'EditedValue', []));
                    txSiteDiffFlag = false;

                    % Cria estrutura que possibilita identificar quais dos
                    % parâmetros foram editados manualmente...
                    % Latitude
                    if ~isequal(txObj.Latitude, txLatitude)
                        txSiteDiffFlag = true;

                        txSiteDiff.Latitude.Status = true;
                        txSiteDiff.Latitude.EditedValue = txObj.Latitude;
                    end

                    % Longitude
                    if ~isequal(txObj.Longitude, txLongitude)
                        txSiteDiffFlag = true;

                        txSiteDiff.Longitude.Status = true;
                        txSiteDiff.Longitude.EditedValue = txObj.Longitude;
                    end

                    % Height
                    if ~isequal(txObj.AntennaHeight, txHeight)
                        txSiteDiffFlag = true;

                        txSiteDiff.AntennaHeight.Status = true;
                        txSiteDiff.AntennaHeight.EditedValue = txObj.AntennaHeight;
                    end

                    if txSiteDiffFlag
                        % Evidenciada alteração no registro. Cria-se nova linha
                        % ou edita-se a existente.
                        if idxAnnotation
                            if isequal(app.rfDataHubAnnotation.TXSite(idxAnnotation), txSiteDiff)
                                return
                            end
                        else
                            idxAnnotation = height(app.rfDataHubAnnotation) + 1;
                        end

                        app.rfDataHubAnnotation(idxAnnotation, :) = {ID, Station, txSiteDiff};
                        layout_AddNewTableStyle(app, 'EditedRows')
                        
                        % Força a atualização do painel HTML e dos plots...
                        app.stationInfo.UserData.idxRFDataHub = [];
                        UITableSelectionChanged(app)
                    else
                        % Não evidenciada alteração no registro. Apaga-se linha,
                        % caso existente. O usuário, aqui, desfez alteração
                        % manualmente (sem pressionar no Refresh).
                        if idxAnnotation
                            app.rfDataHubAnnotation(idxAnnotation, :) = [];
                            layout_AddNewTableStyle(app, 'EditedRows')

                            app.stationInfo.UserData.idxRFDataHub = [];
                            UITableSelectionChanged(app)
                        end
                    end

                case 'Del'
                    [~, ~, ~, idxAnnotation] = referenceTX_checkTXSiteTempList(app);
                    if idxAnnotation
                        app.rfDataHubAnnotation(idxAnnotation, :) = [];
                        layout_AddNewTableStyle(app, 'EditedRows')

                        app.stationInfo.UserData.idxRFDataHub = [];
                        UITableSelectionChanged(app)
                    end
            end
        end

        %-----------------------------------------------------------------%
        function [txObj, ID, Station, idxAnnotation] = referenceTX_checkTXSiteTempList(app)
            idxRFDataHub = getRFDataHubIndex(app);

            % Objeto.
            txObj   = RFLinkObjects(app, 'TX', idxRFDataHub);
            ID      = txObj.ID;
            Station = txObj.Station;

            % Consulta se esse objeto está na lista temporária de estações
            % editadas do RFDataHub.
            [~, idxAnnotation] = ismember(ID, app.rfDataHubAnnotation.ID);
        end

        %-----------------------------------------------------------------%
        function [txLatitude, txLongitude, txHeight] = referenceTX_getRawData(app)
            idxRFDataHub = getRFDataHubIndex(app);

            txLatitude   = round(double(app.rfDataHub.Latitude(idxRFDataHub)),  6);
            txLongitude  = round(double(app.rfDataHub.Longitude(idxRFDataHub)), 6);
            txHeight     = round(str2double(char(app.rfDataHub.AntennaHeight(idxRFDataHub))), 1);
            if txHeight < 0
                txHeight = app.General.RFDataHub.DefaultTX.Height;
            end
        end


        %-----------------------------------------------------------------%
        % TAB: 2
        % PAINEL: ESTAÇÃO RECEPTORA - RX
        %-----------------------------------------------------------------%
        function  rxSite = referenceRX_InitialValue(app)
            rxLatitude  = app.General.RFDataHub.DefaultRX.Latitude;
            rxLongitude = app.General.RFDataHub.DefaultRX.Longitude;
            rxHeight    = app.General.RFDataHub.DefaultRX.Height;

            rxSite = struct('Name',          'RX',        ...
                            'Latitude',      rxLatitude,  ...
                            'Longitude',     rxLongitude, ...
                            'AntennaHeight', rxHeight);
        end

        %-----------------------------------------------------------------%
        function referenceRX_UpdatePanel(app, rxSite)
            app.referenceRX_Latitude.Value  = rxSite.Latitude;
            app.referenceRX_Longitude.Value = rxSite.Longitude;
            app.referenceRX_Height.Value    = rxSite.AntennaHeight;
        end

        %-----------------------------------------------------------------%
        function referenceRX_CalculateDistance(app, rxSite)
            app.rfDataHub.Distance = round(single(deg2km(distance(app.rfDataHub.Latitude,  ...
                                                                  app.rfDataHub.Longitude, ...
                                                                  rxSite.Latitude,         ...
                                                                  rxSite.Longitude))), 1);
            app.referenceRX_Refresh.UserData.DistanceColumnSource = rxSite;
        end

        %-----------------------------------------------------------------%
        function referenceRX_EditOrRefreshReferenceRX(app, operationType)
            arguments
                app 
                operationType char {mustBeMember(operationType, {'Refresh', 'Edit'})}
            end

            refInitialValue         = app.referenceRX_Refresh.UserData.InitialValue;
            refDistanceColumnSource = app.referenceRX_Refresh.UserData.DistanceColumnSource;

            switch operationType
                case 'Refresh'
                    rxSite = refInitialValue;
                    referenceRX_UpdatePanel(app, rxSite)
                case 'Edit'
                    rxSite = RFLinkObjects(app, 'RX');
            end

            if ~isequal(rxSite, refInitialValue)
                app.referenceRX_Refresh.Visible = 1;
            else
                app.referenceRX_Refresh.Visible = 0;
            end

            % Recalcula a coluna "Distance" caso a alteração tenha 
            % ocorrido em "Latitude" ou "Longitude".            
            if ~isequal(rmfield(refDistanceColumnSource, 'AntennaHeight'), rmfield(rxSite, 'AntennaHeight'))
                referenceRX_RefreshTableAndPlots(app, rxSite)
            elseif ~isequal(refDistanceColumnSource.AntennaHeight, rxSite.AntennaHeight)
                app.referenceRX_Refresh.UserData.DistanceColumnSource.AntennaHeight = rxSite.AntennaHeight;
                app.stationInfo.UserData.idxRFDataHub = [];
                UITableSelectionChanged(app)
            end
        end

        %-----------------------------------------------------------------%
        function referenceRX_EditionPanelLayout(app, editionStatus)
            arguments
                app 
                editionStatus char {mustBeMember(editionStatus, {'on', 'off'})}
            end

            hEditFields = findobj(app.referenceRX_Grid.Children, '-not', 'Type', 'uilabel');

            switch editionStatus
                case 'on' 
                    set(app.referenceRX_EditionMode, 'ImageSource', 'Edit_32Filled.png', 'UserData', true)
                    set(hEditFields, 'Editable', true)
                    
                    app.Tab2_Grid.ColumnWidth(5:6) = {16,16};
                    app.referenceRX_EditionConfirm.Enable = 1;
                    app.referenceRX_EditionCancel.Enable  = 1;

                case 'off'
                    set(app.referenceRX_EditionMode, 'ImageSource', 'Edit_32.png', 'UserData', false)
                    set(hEditFields, 'Editable', false)

                    app.Tab2_Grid.ColumnWidth(5:6) = {0,0};
                    app.referenceRX_EditionConfirm.Enable = 0;
                    app.referenceRX_EditionCancel.Enable  = 0;
            end
        end

        %-----------------------------------------------------------------%
        function referenceRX_RefreshTableAndPlots(app, rxSite)
            referenceRX_CalculateDistance(app, rxSite)
            filter_TableFiltering(app)
        end


        %-----------------------------------------------------------------%
        % FILTRAGEM
        %-----------------------------------------------------------------%
        function filter_getReferenceSearch(app)
            % Inicialização do filtro, evitando carregar todas as estações
            % da base no plot.
            if isempty(app.filterTable)
                filterType          = app.General.RFDataHub.DefaultFilter.ColumnLabel;
                filterValue         = app.General.RFDataHub.DefaultFilter.Value;
                filterOperation     = app.General.RFDataHub.DefaultFilter.Operation;
    
                hFilterNames        = findobj(app.filter_SecondaryTypePanel.Children,  'Type', 'uiradiobutton');
                hFilterOperations   = findobj(app.filter_SecondaryValuePanel.Children, 'Type', 'uitogglebutton');
                hFilterValues       = [app.filter_SecondaryNumValue1, ...
                                       app.filter_SecondaryNumValue2, ...
                                       app.filter_SecondaryTextFree,  ...
                                       app.filter_SecondaryTextList];
    
                listOfFilterNames   = {hFilterNames.Text};
                listOfOperations    = {hFilterOperations.Text};
    
                [~, idxFilter]      = ismember(filterType,      listOfFilterNames);
                [~, idxOperation]   = ismember(filterOperation, listOfOperations);
    
                if ~isempty(idxFilter) && ~isempty(idxOperation)
                    hFilterNames(idxFilter).Value   = true;
                    filter_typePanelSelectionChanged(app)
    
                    hFilterOperations(idxOperation).Value = true;
                    filter_SecondaryValuePanelSelectionChanged(app)
    
                    hFilterValues   = hFilterValues(arrayfun(@(x) x.Visible, hFilterValues));
                    for ii = 1:numel(hFilterValues)
                        hFilterValues(ii).Value = filterValue(ii);
                    end
                end
    
                columnName       = filter_FilterType2ColumnNames(app, filterType);
                [~, columnIndex] = ismember(columnName, app.rfDataHub.Properties.VariableNames);
    
                newFilter = {'Node', 1, -3, filterType, filterOperation, columnIndex, filterValue, true, char(matlab.lang.internal.uuid())};
    
                filter_addNewFilter(app, newFilter)
            end
        end

        %-----------------------------------------------------------------%
        function filter_addNewFilter(app, newFilter)
            app.filterTable(end+1, [1:6,8:9]) = newFilter([1:6,8:9]);
            app.filterTable.Value{end} = newFilter{7};
        end

        %-----------------------------------------------------------------%
        function filter_delOldFilter(app, idxFilter)
            % Apaga os ROI's, caso existentes.
            idxROI = find(strcmp(app.filterTable.Type, 'ROI'))';
            for ii = idxROI
                if ismember(ii, idxFilter)
                    UUID = app.filterTable.uuid{ii};
                    delete(findobj(app.UIAxes1.Children, 'UserData', UUID))
                end
            end

            % Apaga os filtros e atualiza os índices dos remanecestes. Por
            % fim, atualiza a árvore e o plot, criando os novos ROI's.
            app.filterTable(idxFilter, :) = [];

            idCurrentList = app.filterTable.ID';
            idNewValue    = 0;

            for ii = idCurrentList
                idNewValue = idNewValue+1;

                app.filterTable.ID(app.filterTable.ID == ii) = idNewValue;
                app.filterTable.RelatedID(app.filterTable.RelatedID == ii) = idNewValue;
            end

            filter_TreeBuilding(app)
            filter_TableFiltering(app)
        end        

        %-----------------------------------------------------------------%
        function columnName = filter_FilterType2ColumnNames(app, filterType)
            filterTypes = ["Fonte", "Frequência", "Largura banda", "Classe emissão", "Entidade", "Fistel", "Serviço", "Estação", "UF",    "Município", "Distância"];
            columnNames = ["Source", "Frequency", "BW",            "EmissionClass",  "Name",     "Fistel", "Service", "Station", "State", "Location",  "Distance"];
            d = dictionary(filterTypes, columnNames);

            columnName = d(filterType);            
        end

        %-----------------------------------------------------------------%
        function filter_TreeBuilding(app)
            if ~isempty(app.filter_Tree.Children)
                delete(app.filter_Tree.Children)
            end

            idx1 = find(strcmp(app.filterTable.Order, 'Node'))';
            if ~isempty(idx1)
                checkedNodes = {};
                for ii = idx1
                    idx2 = find(app.filterTable.RelatedID == app.filterTable.ID(ii))';
                    if isempty(idx2)
                        parentNode = uitreenode(app.filter_Tree, 'Text', sprintf('#%d: RFDataHub.("%s") %s %s', app.filterTable.ID(ii),                        ...
                                                                                                                app.filterTable.Type{ii},                      ...
                                                                                                                app.filterTable.Operation{ii},                 ...
                                                                                                                filter_Value(app, app.filterTable.Value{ii})), ...
                                                                 'NodeData', ii, 'ContextMenu', app.filter_ContextMenu);
                        if app.filterTable.Enable(ii)
                            checkedNodes = [checkedNodes, parentNode];
                        end

                    else
                        parentNode = uitreenode(app.filter_Tree, 'Text', '*.*', ...
                                                                 'NodeData', [ii, idx2], 'ContextMenu', app.filter_ContextMenu);
                        for jj = [ii, idx2]
                            childNode = uitreenode(parentNode, 'Text', sprintf('#%d: RFDataHub.("%s") %s %s', app.filterTable.ID(jj),                        ...
                                                                                                              app.filterTable.Type{jj},                      ...
                                                                                                              app.filterTable.Operation{jj},                 ...
                                                                                                              filter_Value(app, app.filterTable.Value{jj})), ...
                                                               'NodeData', jj, 'ContextMenu', app.filter_ContextMenu);
        
                            if app.filterTable.Enable(jj)
                                checkedNodes = [checkedNodes, childNode];
                            end
                        end
                    end
                end
                app.filter_Tree.CheckedNodes = checkedNodes;
                expand(app.filter_Tree, 'all')
            end

            app.filter_SecondaryReferenceFilter.Items = [{''}, cellstr("#" + string((idx1)))];
        end

        %-----------------------------------------------------------------%
        function filter_TableFiltering(app)
            app.progressDialog.Visible = 'visible';

            % Identifica registro inicialmente selecionado da tabela.
            initialSelectedRowID = '';
            if ~isempty(app.UITable.Selection)
                initialSelectedRowID = app.UITable.Data.ID{app.UITable.Selection(1)};
            end

            % Verifica se todos os filtros geográficos que envolvem ROIs
            % estão válidos, eventualmente recriando os ROIs.
            idxROIFilter = find(app.filterTable.Type == "ROI");
            if ~isempty(idxROIFilter) && any(cellfun(@(x) ~isvalid(x.handle), app.filterTable.Value(idxROIFilter)))
                delete(findobj(app.UIAxes1, 'Tag', 'FilterROI'))

                for ii = idxROIFilter'
                    roiFcn  = class(app.filterTable.Value{ii}.handle);
                    roiSpec = [structUtil.struct2cellWithFields(app.filterTable.Value{ii}.specification), ...
                               {'Color', [0.40,0.73,0.88], 'Tag', 'FilterROI', 'UserData', app.filterTable.uuid{ii}}];
                    if isa(app.filterTable.Value{ii}.handle, 'images.roi.Rectangle')
                        roiSpec = [roiSpec, {'Rotatable', true}];
                    end

                    hROI = plot.ROI.draw(roiFcn, app.UIAxes1, roiSpec);

                    plot.axes.Interactivity.DefaultEnable(app.UIAxes1)                    
                    addlistener(hROI, 'MovingROI', @app.filter_ROICallbacks);
                    addlistener(hROI, 'ROIMoved',  @app.filter_ROICallbacks);
                    addlistener(hROI, 'ObjectBeingDestroyed', @(src, ~)plot.axes.Interactivity.DeleteROIListeners(src));

                    app.filterTable.Value{ii}.handle = hROI;                    
                end
            end

            % Filtragem, preenchendo a tabela e o seu label (nº de linhas).
            idxRFDataHubArray = find(util.TableFiltering(app.rfDataHub, app.filterTable));
            columnGUINames    = {'ID', 'Frequency', 'Description', 'Service', 'Station', 'BW', 'Distance'};

            set(app.UITable, 'Selection', [], 'Data', app.rfDataHub(idxRFDataHubArray, columnGUINames))
            [app.UITable.Data, idxSort] = sortrows(app.UITable.Data, 'Distance');
            app.UITable.UserData = idxRFDataHubArray(idxSort);

            NN = numel(idxRFDataHubArray);
            MM = height(app.rfDataHub);
            app.tool_tableNRows.Text = sprintf('%d de %d registros\n%.1f %%', NN, MM, (NN/MM)*100);

            % Aplicando a seleção inicial da tabela, caso aplicável.
            idxSelectedRow = 0;
            if ~isempty(app.UITable.Data)
                if ~isempty(initialSelectedRowID)
                    [~, idxSelectedRow] = ismember(initialSelectedRowID, app.UITable.Data.ID);
                end

                if ~idxSelectedRow
                    idxSelectedRow = 1;
                end

                app.UITable.Selection = [idxSelectedRow, 1];
                scroll(app.UITable, 'Row', idxSelectedRow)
            end

            layout_AddNewTableStyle(app, 'EditedRows')
            
            % Plots.
            plot_Stations(app)
            plot_RX(app)
            UITableSelectionChanged(app, struct('Source', app.UITable))
            
            plot.axes.StackingOrder.execute(app.UIAxes1, 'appAnalise:RFDATAHUB')
            app.restoreView(1) = struct('ID', 'app.UIAxes1', 'xLim', app.UIAxes1.LatitudeLimits, 'yLim', app.UIAxes1.LongitudeLimits, 'cLim', 'auto');

            app.progressDialog.Visible = 'hidden';
        end


        %-----------------------------------------------------------------%
        % PLOT
        %-----------------------------------------------------------------%
        function plot_Stations(app)
            delete(findobj(app.UIAxes1.Children, '-not', {'Tag', 'FilterROI', '-or', 'Tag', 'TX'}))
            app.stationInfo.UserData.idxRFDataHub = [];

            if ~isempty(app.UITable.Data)
                geolimits(app.UIAxes1, 'auto')

                idxRFDataHubArray = app.UITable.UserData;
                latitudeArray     = app.rfDataHub.Latitude(idxRFDataHubArray);
                longitudeArray    = app.rfDataHub.Longitude(idxRFDataHubArray);

                hStations = geoscatter(app.UIAxes1, latitudeArray, longitudeArray, ...
                    'MarkerEdgeColor', app.config_Station_Color.Value,             ...
                    'SizeData',        app.config_Station_Size.Value,              ...
                    'DisplayName',     'RFDataHub',                                ...
                    'Tag',             'Stations');
                plot.datatip.Template(hStations, 'winRFDataHub.Geographic', app.UITable.Data)
            end
        end

        %-----------------------------------------------------------------%
        function plot_RX(app)
            RX = struct('Latitude',  app.referenceRX_Latitude.Value, ...
                        'Longitude', app.referenceRX_Longitude.Value);

            hRX = geoscatter(app.UIAxes1, RX.Latitude, RX.Longitude, ...
                'Marker',          '^',                              ...
                'MarkerEdgeColor', app.config_RX_Color.Value,        ...
                'MarkerFaceColor', app.config_RX_Color.Value,        ...
                'SizeData',        44*app.config_RX_Size.Value,      ...
                'DisplayName',     'RX',                             ...
                'Tag',             'RX');
            plot.datatip.Template(hRX, 'Coordinates')
        end

        %-----------------------------------------------------------------%
        function plot_TX(app, idxRFDataHub, idxSelectedRow)
            delete(findobj(app.UIAxes1.Children, 'Tag', 'TX'))

            if ~isempty(idxRFDataHub)
                txObj = RFLinkObjects(app, 'TX', idxRFDataHub);

                % Scatter
                hTXScatter = findobj(app.UIAxes1.Children, 'Type', 'scatter', 'Tag', 'TX');
                if isempty(hTXScatter)
                    hTX = geoscatter(app.UIAxes1, txObj.Latitude, txObj.Longitude, ...
                        'LineWidth',       2,                                      ...
                        'Marker',          'o',                                    ...
                        'MarkerEdgeColor', app.config_TX_Color.Value,              ...
                        'MarkerFaceColor', app.config_TX_Color.Value,              ...
                        'SizeData',        20*app.config_TX_Size.Value ,           ...
                        'PickableParts',   'none',                                 ...
                        'DisplayName',     'TX',                                   ...
                        'Tag',             'TX');
                    plot.datatip.Template(hTX, 'Coordinates')
                else
                    set(hTXScatter, 'LatitudeData',  txObj.Latitude, ...
                                    'LongitudeData', txObj.Longitude)
                end

                % DataTip
                if strcmp(app.config_TX_DataTipVisibility.Value, 'on')
                    hTXDataTip = findobj(app.UIAxes1.Children, 'Type', 'datatip', 'Tag', 'TX');
                    if isempty(hTXDataTip)
                        hStations = findobj(app.UIAxes1.Children, 'Tag', 'Stations');
                        datatip(hStations,                   ...
                            'DataIndex',     idxSelectedRow, ...
                            'PickableParts', 'none',         ...
                            'Tag',           'TX');
                    else
                        hTXDataTip.DataIndex = idxSelectedRow;
                    end
                end
            end
        end

        %-----------------------------------------------------------------%
        function plot_createRFLinkPlot(app)
            delete(findobj(app.UIAxes1.Children, 'Tag', 'RFLink'))
            cla(app.UIAxes2)
            delete(findobj(app.UIAxes3.Children, 'Tag', 'Azimuth'))

            if app.tool_RFLinkButton.UserData && ~isempty(app.UITable.Selection)
                idxRFDataHub = getRFDataHubIndex(app);

                app.progressDialog.Visible = 'visible';

                try
                    % OBJETOS TX e RX
                    [txObj, rxObj] = RFLinkObjects(app, 'TX-RX', idxRFDataHub);
        
                    % ELEVAÇÃO DO LINK TX-RX
                    [wayPoints3D, msgWarning] = Get(app.elevationObj, txObj, rxObj, str2double(app.misc_ElevationNPoints.Value), app.misc_ElevationForceSearch.Value, app.misc_ElevationAPISource.Value);
                    if ~isempty(msgWarning)
                        appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
                    end
        
                    % PLOT: RFLink Map
                    geoplot(app.UIAxes1, wayPoints3D(:,1), wayPoints3D(:,2), Color='#c94756', LineStyle='-.', PickableParts='none', DisplayName='Enlace', Tag='RFLink');
                    
                    % PLOT: RFLink
                    plot.RFLink(app.UIAxes2, txObj, rxObj, wayPoints3D, 'light', true, true)
    
                    % PLOT: RFLink Azimuth
                    Azimuth = deg2rad(app.UIAxes2.UserData.Azimuth);
                    polarplot(app.UIAxes3, Azimuth,            app.UIAxes3.RLim(1), 'MarkerEdgeColor', '#c94756', 'MarkerFaceColor', '#c94756', 'Marker', 'o', 'PickableParts', 'none', 'Tag', 'Azimuth');
                    polarplot(app.UIAxes3, Azimuth,            app.UIAxes3.RLim(2), 'MarkerEdgeColor', '#c94756', 'MarkerFaceColor', '#c94756', 'Marker', '^', 'PickableParts', 'none', 'Tag', 'Azimuth');
                    polarplot(app.UIAxes3, [Azimuth, Azimuth], app.UIAxes3.RLim,              'Color', '#c94756', 'LineStyle', '-.',                           'PickableParts', 'none', 'Tag', 'Azimuth');
                    
                catch
                end

                app.progressDialog.Visible = 'hidden';
            end

            plot_PolarAxesVisibility(app)
        end

        %-----------------------------------------------------------------%
        function plot_PolarAxesVisibility(app)
            % Visibilidade do eixo polar, com diagrama de radiação da
            % antena e azimute do enlace, caso aplicáveis.
            app.stationInfoAntennaPattern.Visible = ~isempty(app.UIAxes3.Children);
        end

        %-----------------------------------------------------------------%
        function misc_getChannelReport(app, operationType)
            arguments
                app
                operationType char {mustBeMember(operationType, {'OnlyCache', 'Cache+RealTime', 'RealTime'})}
            end

            if app.tool_PDFButton.UserData && ~isempty(app.UITable.Selection)
                idxRFDataHub = getRFDataHubIndex(app);

                URL = char(app.rfDataHub.URL(idxRFDataHub));
                if strcmp(URL, '-1')
                    layout_restartChannelReport(app)
                    return
                end

                % Caso a URL seja válida, cria-se objeto ChannelReport, caso
                % não existente.            
                if isempty(app.ChannelReportObj)
                    app.ChannelReportObj = RF.ChannelReport;
                end
    
                % A operação poderá ser demorada caso seja do tipo "Cache+RealTime" 
                % ou "RealTime". 
                if ismember(operationType, {'Cache+RealTime', 'RealTime'})
                    app.progressDialog.Visible = 'visible';
                end
    
                [idxCache, msgError] = Get(app.ChannelReportObj, URL, operationType);
                if ~isempty(idxCache)    
                    app.chReportHTML.HTMLSource     = app.ChannelReportObj.cacheMapping.File{idxCache};
                    app.chReportDownloadTime.Text   = sprintf(' DOWNLOAD EM %s', app.ChannelReportObj.cacheMapping.Timestamp{idxCache});
                    app.chReportHotDownload.Visible = 1;
                    app.chReportUndock.Visible      = 1;
    
                else
                    layout_restartChannelReport(app)
    
                    if ismember(operationType, {'Cache+RealTime', 'RealTime'}) && ~isempty(msgError)
                        appUtil.modalWindow(app.UIFigure, 'error', msgError);
                    end
                end
    
                if strcmp(app.progressDialog.Visible, 'visible')
                    app.progressDialog.Visible = 'hidden';
                end

            else
                layout_restartChannelReport(app)
            end
        end

        %-----------------------------------------------------------------%
        function layout_restartChannelReport(app)
            app.chReportHTML.HTMLSource     = 'Warning2.html';
            app.chReportDownloadTime.Text   = '';
            app.chReportHotDownload.Visible = 0;
            app.chReportUndock.Visible      = 0;
        end

        %-----------------------------------------------------------------%
        function layout_AddNewTableStyle(app, operationType, varargin)
            arguments
                app
                operationType char {mustBeMember(operationType, {'EditedRows', 'RowSelectionChanged'})}
            end

            arguments (Repeating)
                varargin
            end

            switch operationType
                case 'EditedRows'
                    styleType = "cell";
                    layout_RemoveOldTableStyle(app, styleType)

                    idxRows = find(contains(app.UITable.Data.ID, app.rfDataHubAnnotation.ID));
                    if ~isempty(idxRows)
                        listOfCells = [idxRows, 2*ones(numel(idxRows), 1)];
                        addStyle(app.UITable, uistyle('Icon', 'Edit_32.png',  'IconAlignment', 'leftmargin'), styleType, listOfCells)
                    end

                case 'RowSelectionChanged'
                    styleType = "row";
                    layout_RemoveOldTableStyle(app, styleType)

                    idxSelectedRow = varargin{1};
                    if idxSelectedRow
                        addStyle(app.UITable, uistyle('BackgroundColor', '#d8ebfa'), styleType, idxSelectedRow)
                    end
            end
        end

        %-----------------------------------------------------------------%
        function layout_RemoveOldTableStyle(app, styleType)
            idxStyle = find(app.UITable.StyleConfigurations.Target == styleType);
            if ~isempty(idxStyle)
                removeStyle(app.UITable, idxStyle)
            end
        end

        %-----------------------------------------------------------------%
        function layout_FilterOperationPanel(app, filterType, filterDefault)            
            layout_FilterDefaultValues(app)
            filter_SecondaryReferenceFilterValueChanged(app)

            hComp = findobj(app.filter_SecondaryValuePanel, 'Type', 'uitogglebutton');
            hCompTagFlag = contains(arrayfun(@(x) x.Tag, hComp, 'UniformOutput', false), filterType);

            set(hComp(hCompTagFlag),  Enable=1)
            set(hComp(~hCompTagFlag), Enable=0)

            selectedButton = app.filter_SecondaryValuePanel.SelectedObject;
            if ~selectedButton.Enable
                switch filterType
                    case {'textList1', 'ROI'}
                        app.filter_SecondaryOperation3.Value = 1; % CONTÉM

                    case 'textList3'
                        app.filter_SecondaryOperation2.Value = 1; % DIFERENTE

                    otherwise
                        app.filter_SecondaryOperation1.Value = 1; % IGUAL
                end
            end

            switch filterType
                case 'numeric'
                    app.filter_SecondaryNumValue1.Visible    = 1;
                    app.filter_SecondaryNumSeparator.Visible = 1;
                    app.filter_SecondaryNumValue2.Visible    = 1;
                    app.filter_SecondaryTextFree.Visible     = 0;
                    app.filter_SecondaryTextList.Visible     = 0;

                case 'textFree'
                    app.filter_SecondaryNumValue1.Visible    = 0;
                    app.filter_SecondaryNumSeparator.Visible = 0;
                    app.filter_SecondaryNumValue2.Visible    = 0;
                    app.filter_SecondaryTextFree.Visible     = 1;
                    app.filter_SecondaryTextList.Visible     = 0;

                case {'textList1', 'textList2', 'ROI'}
                    app.filter_SecondaryNumValue1.Visible    = 0;
                    app.filter_SecondaryNumSeparator.Visible = 0;
                    app.filter_SecondaryNumValue2.Visible    = 0;
                    app.filter_SecondaryTextFree.Visible     = 0;
                    app.filter_SecondaryTextList.Visible     = 1;
                    app.filter_SecondaryTextList.Items       = filterDefault;

                case 'textList3'
                    app.filter_SecondaryNumValue1.Visible    = 0;
                    app.filter_SecondaryNumSeparator.Visible = 0;
                    app.filter_SecondaryNumValue2.Visible    = 0;
                    app.filter_SecondaryTextFree.Visible     = 0;
                    app.filter_SecondaryTextList.Visible     = 1;
                    app.filter_SecondaryTextList.Items       = filterDefault;
            end
        end

        %-----------------------------------------------------------------%
        function layout_FilterDefaultValues(app)
            app.filter_SecondaryNumValue1.Value       = -1;
            app.filter_SecondaryNumValue2.Value       = -1;
            app.filter_SecondaryTextFree.Value        = '';
            app.filter_SecondaryTextList.Items        = {};
            app.filter_SecondaryLogicalOperator.Items = {'E (&&)'};
        end

        %-----------------------------------------------------------------%
        function filterValue = filter_Value(app, filterValue)
            if isnumeric(filterValue)
                if isscalar(filterValue)
                    filterValue = string(filterValue);
                else
                    filterValue = "[" + strjoin(string(filterValue), ', ') + "]";
                end
            elseif ischar(filterValue)
                filterValue = sprintf('"%s"', upper(filterValue));
            else
                filterValue = '⌂';
            end
        end

        %-----------------------------------------------------------------%
        function filter_ROICallbacks(app, src, event)
            switch(event.EventName)
                case 'MovingROI'
                    plot.axes.Interactivity.DefaultDisable(app.UIAxes1)
                    
                case 'ROIMoved'
                    plot.axes.Interactivity.DefaultEnable(app.UIAxes1)
                    
                    filter_TableFiltering(app)
                    if isvalid(event.Source)
                        uistack(event.Source, 'top')
                    end
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, filterTable, rfDataHubAnnotation)
            
            app.mainApp       = mainApp;
            app.General       = mainApp.General;
            app.rootFolder    = mainApp.rootFolder;
    
            if nargin == 4
                app.filterTable = filterTable;
                app.rfDataHubAnnotation = rfDataHubAnnotation;
            end
    
            app.UIFigure.Position(4) = 660;
            app.GridLayout.ColumnWidth(7:10) = {0,0,0,0};
    
            % Em sendo executado como módulo do appAnalise, o app pode
            % estar em modo DOCK ou UNDOCK, o que é definido em configuração
            % no próprio appAnalise.
            if app.isDocked
                app.GridLayout.Padding(4) = 19;
                app.jsBackDoor = mainApp.jsBackDoor;
                startup_Controller(app)
            else
                appUtil.winPosition(app.UIFigure)
                startup_timerCreation(app)
            end
            
            %--------------------------------------------------------------
            % Limpar arquivos mbtiles da pasta cache
            mbtilesFiles = dir(fullfile(app.rootFolder, '..', 'cache'));
            for k = 1:length(mbtilesFiles)
                 if ~((strcmp('.', mbtilesFiles(k).name)) || (strcmp('..', mbtilesFiles(k).name)))
                     delete(fullfile(mbtilesFiles(k).folder, mbtilesFiles(k).name));
                 end
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn')
            delete(app)
            
        end

        % Image clicked function: menu_Button1Icon, menu_Button2Icon, 
        % ...and 2 other components
        function general_ControlPanelSelectionChanged(app, event)
            idx = str2double(event.Source.Tag);
            NN  = numel(app.menu_MainGrid.ColumnWidth);

            for ii = 1:NN
                switch ii
                    case idx
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = "1x";', ii))
                    otherwise
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = 0;',    ii))
                end
            end

            columnWidth      = repmat({22}, 1, NN);
            columnWidth(idx) = {'1x'};

            app.menu_MainGrid.ColumnWidth   = columnWidth;
            app.menuUnderline.Layout.Column = idx;
            app.ControlTabGroup.SelectedTab = app.ControlTabGroup.Children(idx);

            jsBackDoor_Customizations(app, idx)
            
        end

        % Image clicked function: tool_ControlPanelVisibility, 
        % ...and 3 other components
        function tool_InteractionImageClicked(app, event)
            
            switch event.Source
                case app.tool_ControlPanelVisibility
                    if app.GridLayout.ColumnWidth{2}
                        app.tool_ControlPanelVisibility.ImageSource = 'ArrowRight_32.png';
                        app.GridLayout.ColumnWidth(2:3) = {0,0};
                    else
                        app.tool_ControlPanelVisibility.ImageSource = 'ArrowLeft_32.png';
                        app.GridLayout.ColumnWidth(2:3) = {320,10};
                    end

                case app.tool_TableVisibility
                    app.tool_TableVisibility.UserData = mod(app.tool_TableVisibility.UserData + 1, 3);
                    switch app.tool_TableVisibility.UserData
                        case 0
                            app.UITable.Visible           = 0;
                            app.GridLayout.RowHeight(2:5) = {23,'1x',0,0};
                        case 1
                            app.UITable.Visible           = 1;
                            app.GridLayout.RowHeight(2:5) = {23,'1x',10,'.4x'};
                        case 2
                            app.UITable.Visible           = 1;
                            app.GridLayout.RowHeight(2:5) = {0,0,0,'1x'};
                    end

                case app.tool_PDFButton
                    app.tool_PDFButton.UserData = ~app.tool_PDFButton.UserData;
                    if app.tool_PDFButton.UserData
                        % Se a tabela estiver ocupando toda a tela, então
                        % muda-se o layout.
                        if app.tool_TableVisibility.UserData == 2
                            app.tool_TableVisibility.UserData = 1;
                            app.GridLayout.RowHeight(2:5) = {23,3,'1x',10,'.4x'};
                        end

                        app.GridLayout.ColumnWidth(7:10) = {10,'1x',22,22};
                    else
                        app.GridLayout.ColumnWidth(7:10) = {0,0,0,0};
                    end
                    misc_getChannelReport(app, 'Cache+RealTime')

                case app.tool_RFLinkButton
                    app.tool_RFLinkButton.UserData = ~app.tool_RFLinkButton.UserData;

                    if app.tool_RFLinkButton.UserData
                        % Se a tabela estiver ocupando toda a tela, então
                        % muda-se o layout. O pause é uma espécie de "drawnow"
                        % e garante que o plot será realizado corretamente.
                        if app.tool_TableVisibility.UserData == 2
                            app.tool_TableVisibility.UserData = 1;
                            app.GridLayout.RowHeight(2:5) = {23,'1x',10,'.4x'};
                            pause(.100)
                        end

                        %app.UIAxes1.Layout.TileSpan = [1,2];
                        
                        set(findobj(app.UIAxes1), 'Visible', 0)
                        set(findobj(app.UIAxes2), 'Visible', 1)
                        cla(app.UIAxes2);
                        app.GridLayout2.RowHeight(8) = {123};
                        app.rxLongitudeEditField.Visible = "on";
                        app.rxLatitudeEditFieldLabel.Visible = "on";
                        app.rxLatitudeEditField.Visible = "on";
                        app.rxLongitudeEditFieldLabel.Visible = "on";

                    else
                        set(findobj(app.UIAxes1), 'Visible', 1)
                        app.UIAxes1.Layout.TileSpan = [2,2];
                        set(findobj(app.UIAxes2), 'Visible', 0)
                        app.GridLayout2.RowHeight(8) = {64};
                        app.rxLongitudeEditField.Visible = "off";
                        app.rxLatitudeEditFieldLabel.Visible = "off";
                        app.rxLatitudeEditField.Visible = "off";
                        app.rxLongitudeEditFieldLabel.Visible = "off";

                    end
                    %plot_createRFLinkPlot(app)
            end

        end

        % Image clicked function: tool_ExportButton
        function tool_exportButtonPushed(app, event)

                nameFormatMap = {'*.xlsx', 'Excel (*.xlsx)'};
                defaultName   = class.Constants.DefaultFileName(app.General.fileFolder.userPath, 'RFDataHub', -1); 
                fileFullPath  = appUtil.modalWindow(app.UIFigure, 'uiputfile', '', nameFormatMap, defaultName);
                if isempty(fileFullPath)
                    return
                end
                
                app.progressDialog.Visible = 'visible';

                try
                    idxRFDataHubArray = app.UITable.UserData;
                    tempRFDataHub = model.RFDataHub.ColumnNames(app.rfDataHub(idxRFDataHubArray,1:29), 'eng2port');
                    writetable(tempRFDataHub, fileFullPath, 'WriteMode', 'overwritesheet')
                catch ME
                    appUtil.modalWindow(app.UIFigure, 'warning', getReport(ME));
                end

                app.progressDialog.Visible = 'hidden';

        end

        % Image clicked function: axesTool_RegionZoom, axesTool_RestoreView
        function axesTool_InteractionImageClicked(app, event)
            
            switch event.Source
                case app.axesTool_RestoreView
                    geolimits(app.UIAxes1, app.restoreView(1).xLim, app.restoreView(1).yLim)

                case app.axesTool_RegionZoom
                    plot.axes.Interactivity.GeographicRegionZoomInteraction(app.UIAxes1, app.axesTool_RegionZoom)
                
            end

        end

        % Image clicked function: chReportHotDownload, chReportUndock
        function tool_chReportPanelImageClicked(app, event)
            
            if strcmp(app.chReportHTML.HTMLSource, 'Warning2.html')
                return
            end

            switch event.Source
                case app.chReportHotDownload
                    misc_getChannelReport(app, 'RealTime')

                case app.chReportUndock
                    switch app.mainApp.executionMode
                        case 'webApp'
                            idxRFDataHub = getRFDataHubIndex(app);
                            URL = char(app.rfDataHub.URL(idxRFDataHub));
                            web(URL, '-new')
                        otherwise
                            ccTools.fcn.OperationSystem('openFile', app.chReportHTML.HTMLSource)
                    end                    
                    tool_InteractionImageClicked(app, struct('Source', app.tool_PDFButton))
            end

        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            
            [idxRFDataHub, idxSelectedRow] = getRFDataHubIndex(app);

            % Caso nenhum registro atenda aos critérios de filtragem,
            % reinicializa a área de visualização do app.
            if isempty(idxRFDataHub)
                % Painel:
                app.referenceTX_Refresh.Visible     = 0;
                app.referenceTX_EditionMode.Visible = 0;
                app.referenceTX_Latitude.Value      = -1;
                app.referenceTX_Longitude.Value     = -1;
                app.referenceTX_Height.Value        = 0;

                ui.TextView.update(app.stationInfo, '');
                app.stationInfoImage.Visible = 'on';

                % Área de plot/PDF:
                delete(findobj(app.UIAxes1.Children, 'Tag', 'TX'))
                cla(app.UIAxes2)
                cla(app.UIAxes3)

                plot_PolarAxesVisibility(app)
                layout_restartChannelReport(app)
                return

            % Caso a alteração na seleção da tabela seja restrita à coluna,
            % por exemplo, mantendo-se selecionada a mesma linha, não será 
            % realizado um novo plot.
            elseif isequal(app.stationInfo.UserData.idxRFDataHub, idxRFDataHub)
                return
            end

            app.stationInfo.UserData.idxRFDataHub = idxRFDataHub;
            layout_AddNewTableStyle(app, 'RowSelectionChanged', idxSelectedRow)
            
            % Estação transmissora - TX
            referenceTX_UpdatePanel(app, idxRFDataHub)
            if app.referenceTX_EditionMode.UserData
                referenceTX_EditionModeImageClicked(app, struct('Source', app.referenceTX_EditionMode))
            end

            % Painel HTML
            ui.TextView.update(app.stationInfo, util.HtmlTextGenerator.Station(app.rfDataHub, idxRFDataHub, app.rfDataHubLOG, app.General));
            app.stationInfoImage.Visible = 'off';

            % Painel PDF
            if app.rfDataHub.Source(idxRFDataHub) == "MOSAICO-SRD"
                misc_getChannelReport(app, 'Cache+RealTime')
            else
                layout_restartChannelReport(app)
            end
                
            % Plot "AntennaPattern"
            % O bloco try/catch protege possível erro no parser da informação
            % do Mosaico. Como exposto em model.RFDataHub.parsingAntennaPattern
            % foram identificados quatro formas de armazenar a informação.
            cla(app.UIAxes3)
            if app.rfDataHub.AntennaPattern(idxRFDataHub) ~= "-1"
                try
                    [angle, gain] = model.RFDataHub.parsingAntennaPattern(app.rfDataHub.AntennaPattern(idxRFDataHub), 360);
                    hAntennaPattern = polarplot(app.UIAxes3, angle, gain, 'Tag', 'AntennaPattern');
                    plot.datatip.Template(hAntennaPattern, "AntennaPattern")
                catch
                end
            end

            % Plot "TX"
            plot_TX(app, idxRFDataHub, idxSelectedRow)

            % Plot "RFLink"
            plot_createRFLinkPlot(app)
            
        end

        % Image clicked function: referenceTX_EditionCancel, 
        % ...and 3 other components
        function referenceTX_EditionModeImageClicked(app, event)
            
            switch event.Source
                case app.referenceTX_Refresh
                    referenceTX_AddOrDelTXSiteTempList(app, 'Del')
                    referenceTX_EditionPanelLayout(app, 'off')

                case app.referenceTX_EditionMode
                    app.referenceTX_EditionMode.UserData = ~app.referenceTX_EditionMode.UserData;
                    
                    if app.referenceTX_EditionMode.UserData
                        referenceTX_EditionPanelLayout(app, 'on')
                        focus(app.referenceTX_Latitude)
                    else
                        referenceTX_EditionModeImageClicked(app, struct('Source', app.referenceTX_EditionCancel))
                    end

                case app.referenceTX_EditionConfirm
                    referenceTX_AddOrDelTXSiteTempList(app, 'Add')
                    referenceTX_EditionPanelLayout(app, 'off')

                case app.referenceTX_EditionCancel
                    idxRFDataHub = getRFDataHubIndex(app);
                    referenceTX_UpdatePanel(app, idxRFDataHub)
                    referenceTX_EditionPanelLayout(app, 'off')
            end

        end

        % Image clicked function: referenceRX_EditionCancel, 
        % ...and 3 other components
        function referenceRX_EditionModeImageClicked(app, event)
            
            switch event.Source
                case app.referenceRX_Refresh
                    referenceRX_EditOrRefreshReferenceRX(app, 'Refresh')
                    referenceRX_EditionPanelLayout(app, 'off')

                case app.referenceRX_EditionMode
                    app.referenceRX_EditionMode.UserData = ~app.referenceRX_EditionMode.UserData;

                    if app.referenceRX_EditionMode.UserData
                        referenceRX_EditionPanelLayout(app, 'on')
                        focus(app.referenceRX_Latitude)
                    else
                        referenceRX_EditionModeImageClicked(app, struct('Source', app.referenceRX_EditionCancel))
                    end

                case app.referenceRX_EditionConfirm
                    referenceRX_EditOrRefreshReferenceRX(app, 'Edit')
                    referenceRX_EditionPanelLayout(app, 'off')

                case app.referenceRX_EditionCancel
                    rxSite = app.referenceRX_Refresh.UserData.DistanceColumnSource;
                    referenceRX_UpdatePanel(app, rxSite)
                    referenceRX_EditionPanelLayout(app, 'off')
            end        

        end

        % Selection changed function: filter_SecondaryTypePanel
        function filter_typePanelSelectionChanged(app, event)
                        
            selectedButton = app.filter_SecondaryTypePanel.SelectedObject;
            switch selectedButton
                case app.filter_SecondaryType1                            % BASE DE DADOS
                    filterType    = 'textList1';
                    filterDefault = app.rfDataHubSummary.Source.RawCategories;

                case {app.filter_SecondaryType2, ...                      % FREQUÊNCIA
                      app.filter_SecondaryType3, ...                      % LARGURA BANDA
                      app.filter_SecondaryType6, ...                      % FISTEL
                      app.filter_SecondaryType7, ...                      % SERVIÇO
                      app.filter_SecondaryType8, ...                      % ESTAÇÃO
                      app.filter_SecondaryType11}                         % DISTÂNCIA
                    filterType    = 'numeric';
                    filterDefault = {};

                case {app.filter_SecondaryType4, ...                      % CLASSE EMISSÃO (DESIGNAÇÃO)
                      app.filter_SecondaryType5, ...                      % ENTIDADE
                      app.filter_SecondaryType10}                         % MUNICÍPIO
                    filterType    = 'textFree';
                    filterDefault = {};

                case app.filter_SecondaryType9                            % UF
                    filterType    = 'textList2';
                    filterDefault = app.rfDataHubSummary.State.Categories;

                case app.filter_SecondaryType12                           % ROI
                    filterType    = 'ROI';
                    filterDefault = {'ROI:Círculo', 'ROI:Retângulo', 'ROI:Polígono'};

                case app.filter_SecondaryType13                           % Padrão de antena
                    filterType    = 'textList3';
                    filterDefault = {'-1'};
            end

            layout_FilterOperationPanel(app, filterType, filterDefault)
            filter_SecondaryValuePanelSelectionChanged(app)
            
        end

        % Selection changed function: filter_SecondaryValuePanel
        function filter_SecondaryValuePanelSelectionChanged(app, event)
            
            selectedButton = app.filter_SecondaryValuePanel.SelectedObject;
            switch selectedButton
                case {app.filter_SecondaryOperation9, app.filter_SecondaryOperation10}
                    app.filter_SecondaryNumSeparator.Visible = 1;
                    app.filter_SecondaryNumValue2.Visible    = 1;
                    
                otherwise
                    app.filter_SecondaryNumSeparator.Visible = 0;
                    app.filter_SecondaryNumValue2.Visible    = 0;
            end
            
        end

        % Value changed function: filter_SecondaryReferenceFilter
        function filter_SecondaryReferenceFilterValueChanged(app, event)
            
            value = app.filter_SecondaryReferenceFilter.Value;
            if isempty(value)
                app.filter_SecondaryLogicalOperator.Items = {'E (&&)'};
            else
                app.filter_SecondaryLogicalOperator.Items = {'OU (||)'};
            end
            
        end

        % Image clicked function: filter_AddImage
        function filter_addFilter(app, event)
            
            selectedFilterType      = app.filter_SecondaryTypePanel.SelectedObject;
            selectedFilterOperation = app.filter_SecondaryValuePanel.SelectedObject;

            if isempty(app.filter_SecondaryReferenceFilter.Value)
                Order     = 'Node';
                RelatedID = -1;
            else
                Order     = 'Child';
                RelatedID = str2double(app.filter_SecondaryReferenceFilter.Value(2:end));
            end
            UUID = char(matlab.lang.internal.uuid());

            switch selectedFilterType
                case {app.filter_SecondaryType1, ...                      % BASE DE DADOS
                      app.filter_SecondaryType9, ...                      % UF
                      app.filter_SecondaryType13}                         % PADRÃO DA ANTENA
                    Value = app.filter_SecondaryTextList.Value;

                case {app.filter_SecondaryType2, ...                      % FREQUÊNCIA
                      app.filter_SecondaryType3, ...                      % LARGURA BANDA
                      app.filter_SecondaryType6, ...                      % FISTEL
                      app.filter_SecondaryType7, ...                      % SERVIÇO
                      app.filter_SecondaryType8, ...                      % ESTAÇÃO
                      app.filter_SecondaryType11}                         % DISTÂNCIA

                    if ismember(selectedFilterOperation.Text, {'<>', '><'})
                        Value = [app.filter_SecondaryNumValue1.Value, ...
                                 app.filter_SecondaryNumValue2.Value];
                    else
                        Value = app.filter_SecondaryNumValue1.Value;
                    end

                case {app.filter_SecondaryType4, ...                      % CLASSE EMISSÃO (DESIGNAÇÃO)
                      app.filter_SecondaryType5, ...                      % ENTIDADE
                      app.filter_SecondaryType10}                         % MUNICÍPIO
                    Value = app.filter_SecondaryTextFree.Value;

                case app.filter_SecondaryType12                           % ROI
                    hROI = [];

                    plot.axes.Interactivity.DefaultDisable(app.UIAxes1)
                    pause(.1)

                    switch app.filter_SecondaryTextList.Value
                        case 'ROI:Círculo';   roiFcn = 'drawcircle';    roiNameArgument = '';
                        case 'ROI:Retângulo'; roiFcn = 'drawrectangle'; roiNameArgument = 'Rotatable=true, ';
                        case 'ROI:Polígono';  roiFcn = 'drawpolygon';   roiNameArgument = '';
                    end
                    eval(sprintf('hROI = %s(app.UIAxes1, Color=[0.40,0.73,0.88], LineWidth=1, Deletable=0, FaceSelectable=0, %sTag="FilterROI", UserData="%s");', roiFcn, roiNameArgument, UUID))
                    plot.axes.Interactivity.DefaultEnable(app.UIAxes1)

                    if isempty(hROI.Position)
                        delete(hROI)
                        return
                    end
                    addlistener(hROI, 'MovingROI', @app.filter_ROICallbacks);
                    addlistener(hROI, 'ROIMoved',  @app.filter_ROICallbacks);
                    addlistener(hROI, 'ObjectBeingDestroyed', @(src, ~)plot.axes.Interactivity.DeleteROIListeners(src));
                    Value = struct('handle', hROI, 'specification', plot.ROI.specification(hROI));
            end

            newFilter     = {Order, height(app.filterTable)+1, RelatedID,           ...
                             selectedFilterType.Text, selectedFilterOperation.Text, ...
                             str2double(selectedFilterType.Tag), Value, true, UUID};
            newFilterFlag = true;

            for ii = 1:height(app.filterTable)
                if isequal(app.filterTable{ii,[4,5,7]}, newFilter([4,5,7]))
                    newFilterFlag = false;
                    break
                end
            end

            if ~newFilterFlag
                msg = 'Filtro já incluído!';
                appUtil.modalWindow(app.UIFigure, 'warning', msg);
                if exist('hROI', 'var')
                    delete(hROI)
                end
                
                return
            end

            filter_addNewFilter(app, newFilter)
            filter_TreeBuilding(app)
            filter_TableFiltering(app)

        end

        % Menu selected function: filter_delAllButton, filter_delButton
        function filter_delFilter(app, event)
            
            if isempty(app.filterTable)
                return
            end

            switch event.Source
                case app.filter_delButton
                    if isempty(app.filter_Tree.SelectedNodes)
                        return
                    end
                    idx1 = app.filter_Tree.SelectedNodes.NodeData;
                    
                    % Identifica se algum dos fluxos selecionado é um nó de
                    % filtros, inserindo na lista os seus filhos.
                    idx1 = [idx1, find(ismember(app.filterTable.RelatedID, idx1))'];

                case app.filter_delAllButton
                    idx1 = 1:height(app.filterTable);
            end 
    
            if ~isempty(idx1)
                filter_delOldFilter(app, idx1);
            end

        end

        % Callback function: filter_Tree
        function filter_TreeCheckedNodesChanged(app, event)
            
            hTree             = findobj(app.filter_Tree, '-not', 'Type', 'uicheckboxtree');
            
            hTreeNode         = arrayfun(@(x) x.NodeData, hTree, 'UniformOutput', false);
            hTreeNodeDataList = unique(horzcat(hTreeNode{:}));

            hCheckedNode      = arrayfun(@(x) x.NodeData, app.filter_Tree.CheckedNodes, 'UniformOutput', false);
            hCheckedNodeData  = unique(horzcat(hCheckedNode{:}));

            disableIndexList  = setdiff(hTreeNodeDataList, hCheckedNodeData);
            enableIndexList   = setdiff((1:height(app.filterTable))', disableIndexList);

            app.filterTable.Enable(disableIndexList) = false;
            app.filterTable.Enable(enableIndexList)  = true;

            filter_TableFiltering(app)
            
        end

        % Callback function: config_RX_Color, config_Station_Color, 
        % ...and 1 other component
        function config_geoAxesColorParameterChanged(app, event)
            
            selectedColor = event.Source.Value;

            switch event.Source
                case app.config_Station_Color
                    set(findobj(app.UIAxes1.Children, 'Tag', 'Stations'), 'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)

                case app.config_TX_Color
                    set(findobj(app.UIAxes1.Children, 'Type', 'scatter', 'Tag', 'TX'), 'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)

                case app.config_RX_Color
                    set(findobj(app.UIAxes1.Children, 'Tag', 'RX'),  'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)
            end

        end

        % Callback function: config_Basemap, config_Colormap, 
        % ...and 4 other components
        function config_geoAxesOthersParametersChanged(app, event)
            
            switch event.Source
                case app.config_Basemap
                    app.UIAxes1.Basemap = app.config_Basemap.Value;
                    switch app.config_Basemap.Value
                        case {'darkwater', 'none'}
                            app.UIAxes1.Grid = 'on';
                        otherwise
                            app.UIAxes1.Grid = 'off';
                    end
                    return

                case app.config_Colormap
                    if strcmp(app.UIAxes1.UserData.Colormap, event.Value)
                        return
                    end

                    plot.axes.Colormap(app.UIAxes1, event.Value)

                case app.config_TX_DataTipVisibility
                    switch app.config_TX_DataTipVisibility.Value
                        case 'on'
                            [idxRFDataHub, idxSelectedRow] = getRFDataHubIndex(app);
                            plot_TX(app, idxRFDataHub, idxSelectedRow)
                            plot.axes.StackingOrder.execute(app.UIAxes1, 'appAnalise:RFDATAHUB')
                            
                        case 'off'
                            hDataTip = findobj(app.UIAxes1.Children, 'Type', 'datatip', 'Tag', 'TX');
                            delete(hDataTip)
                    end

                case app.config_Station_Size
                    set(findobj(app.UIAxes1.Children, 'Type', 'scatter', 'Tag', 'Stations'), 'SizeData', event.Value)

                case app.config_TX_Size
                    set(findobj(app.UIAxes1.Children, 'Type', 'scatter', 'Tag', 'TX'),       'SizeData', 20*event.Value)

                case app.config_RX_Size
                    set(findobj(app.UIAxes1.Children,                    'Tag', 'RX'),       'SizeData', 44*event.Value)
            end

        end

        % Image clicked function: config_Refresh
        function config_RefreshImageClicked(app, event)
            
            % ToDo: Pendente finalizar a implementação dessa funcionalidade.
            % Não gostei de plotar novamente a coisa... preciso identificar
            % os parâmetros que foram alterados, chamando individualmente
            % os callbacks de cada parâmetro. O botão ficará invisível até 
            % ajuste desses pontos.

            app.General = app.mainApp.General;

            app.misc_ElevationAPISource.Value = app.General.Elevation.Server;
            app.misc_ElevationNPoints.Value      = num2str(app.General.Elevation.Points);

            % % Eixo geográfico - app.UIAxes1
            app.config_Colormap.Value             = 'turbo';            
            app.config_Station_Color.Value        = [0 1 1];
            app.config_Station_Size.Value         = 1;
            app.config_TX_Color.Value             = [0.7882 0.2784 0.3412];
            app.config_TX_Size.Value              = 1;
            app.config_TX_DataTipVisibility.Value = 'off';
            app.config_RX_Color.Value             = [0.7882 0.2784 0.3373];
            app.config_RX_Size.Value              = 1;
            
            % % Atualiza o plot...
            app.stationInfo.UserData.idxRFDataHub = [];
            filter_TableFiltering(app)            

            app.config_Refresh.Visible = 0;

        end

        % Image clicked function: clutterButton, prop_terrainButton, 
        % ...and 2 other components
        function ImageClicked(app, event)
            
            [fileName, filePath] = uigetfile();
            figure(app.UIFigure)

            if isequal(fileName, 0)
                return
            end
            fileFullName = fullfile(filePath, fileName);

            switch event.Source
                case app.prop_terrainButton
                    app.prop_terrain.Value = fileFullName;
                case app.clutterButton
                    app.ClutterEditField.Value = fileFullName;
                case app.rxAntennaButton
                    app.ArquivoantenaEditField.Value = fileFullName;
                case app.txAntennaButton
                    app.txAntenna.Value = fileFullName;
                    app.txAntenna.UserData = fileFullName;
            end

        end

        % Value changed function: EstaoEditField
        function EstaoEditFieldValueChanged(app, event)
            if ~isempty(app.EstaoEditField.Value)
                filtro = app.rfDataHub.Station == str2double(app.EstaoEditField.Value);
                estacao = app.rfDataHub(filtro, :);
                if ~isempty(estacao)
                    %perdaLinha = str2double(string(estacao.LineAttenuation)) * str2double(string(estacao.LineLength));
                    perdaLinha =  utils.numValRDH(estacao.LineAttenuation);
                    perdaTotal = 10 ^ (-(perdaLinha +  utils.numValRDH(estacao.LineAccessoryLosses)) / 10);
                    app.IdentificaoEditField.Value = string(estacao.Name);
                    app.FrequnciaMHzEditField.Value = estacao.Frequency;
                    app.PotnciaWattsEditField.Value =  utils.numValRDH(estacao.TransmitterPower) * perdaTotal;
                    app.LatitudeEditField.Value = estacao.Latitude;
                    app.LongitudeEditField.Value = estacao.Longitude;
                    app.AlturaEditField_2.Value = utils.numValRDH(estacao.AntennaHeight);
                    app.TiltEditField.Value = utils.numValRDH(estacao.AntennaElevation);
                    app.AzimuteEditField.Value = utils.numValRDH(estacao.AntennaAzimuth);

                    %-----------------------------------------------------------------------------------------------
                    % Verifica se foi informado um padrão de antena
                    if ~(strcmp(string(estacao.AntennaPattern), '-1'))
                        app.txAntenna.UserData = string(estacao.AntennaPattern);
                        app.txAntenna.Value = '<padrão de antena carregado do RfDataHub>';
                    else
                        app.txAntenna.UserData = '';
                        app.txAntenna.Value = '';
                    end
                    %------------------------------------------------------------------------------------------------

                    app.txAntenna.HorizontalAlignment = 'center';
                    app.EstaoEditField.BackgroundColor = [1 1 1];
                else
                    utils.limpaFormEstacaoTX(app);
                    app.EstaoEditField.BackgroundColor = [1 0 0 ];
                end
            else
                utils.limpaFormEstacaoTX(app);
                app.EstaoEditField.BackgroundColor = [1 1 1];
            end
            
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed3(app, event)
            %dlg = appUtil.modalWindow(app.UIFigure, 'progressdlg', '<font style="font-size: 12px;">Em andamento...</font>');
            app.progressDialog.Visible = 'visible';
            try
                modelDict = dictionary(["COST-231/Hata", "ITU P526", "ITU P1812"], ...
                    ["Hata", "P.526", "P.1812"]);
                dadosPredicao = struct('modeloPredicao', modelDict(app.prop_Model.Value), ...
                    'frequencia',     app.FrequnciaMHzEditField.Value * 1e+6, ...
                    'dadosRelevo',    app.prop_terrain.Value, ...
                    'dadosClutter',   app.ClutterEditField.Value, ...
                    'Movel', struct('Antena', struct('Altura',  app.AlturaEditField.Value)), ...
                    'Base',  struct('Nome', app.IdentificaoEditField.Value, ...
                    'Latitude', app.LatitudeEditField.Value, ...
                    'Longitude', app.LongitudeEditField.Value, ...
                    'Potencia', app.PotnciaWattsEditField.Value, ...
                    'Antena', struct('Altura', app.AlturaEditField_2.Value, ....
                    'ArquivoDados', app.txAntenna.UserData, ...
                    'Modelo', 'AIR6419', ...
                    'Funcao', 'TX', ...
                    'Azimute', app.AzimuteEditField.Value, ...
                    'tiltMecanico', app.TiltEditField.Value, ...
                    'Tipo', 'isotropic')));

                %---------------------------------------------------------
                % verifica se está na tela de analise detalhada
                if app.tool_RFLinkButton.UserData
                    utils.plot_perfil(dadosPredicao, app.rxLatitudeEditField.Value, app.rxLongitudeEditField.Value, app.UIAxes2)

                else
                    raio_metros   = app.RaiometrosEditField.Value;
                    [lb, prx] = utils.predicao_area(raio_metros, dadosPredicao);
                    utils.plota_predicao(dadosPredicao, prx, 1, app.UIAxes1);
                end
            catch ME
                appUtil.modalWindow(app.UIFigure, 'warning', getReport(ME));
            end

            %delete(dlg);
            app.progressDialog.Visible = 'hidden';


        end

        % Image clicked function: axesTool_ChangeMapLayer
        function axesTool_ChangeMapLayerImageClicked(app, event)
            switch event.Source

                case app.axesTool_ChangeMapLayer
                    app.progressDialog.Visible = 'visible';
                    atualBasemap = app.UIAxes1.Basemap;
                    basemaps = app.General.propagation.basemapsLayers;
                    numBasemaps = size(basemaps, 1);
                    idx = app.baseMapIdx.Value;

                    ehCustomBasemap = isempty(find(strcmpi(atualBasemap, basemaps), 1));
                    app.baseMapIdx.Value = mod(idx, numBasemaps) + 1;

                    % Se estiver usando o custom basemap PredicaoPropagRF idx = 1
                    if ehCustomBasemap
                        utils.reexibe_predicao(app.UIAxes1, basemaps{app.baseMapIdx.Value})
                    else
                        dlg = appUtil.modalWindow(app.UIFigure, 'progressdlg', '<font style="font-size: 12px;">Em andamento...</font>');
                        try
                            app.UIAxes1.Basemap = basemaps{app.baseMapIdx.Value};
                        catch ME
                            appUtil.modalWindow(app.UIFigure, 'warning', getReport(ME));
                        end
                    end
                    app.progressDialog.Visible = 'hidden';

            end
        end

        % Button pushed function: LimparButton
        function LimparButtonPushed(app, event)
            % app.FrequnciaMHzEditField.Value = 0;
            % app.prop_terrain.Value = '';
            % app.ClutterEditField.Value = '';
            % app.AlturaEditField.Value = 1.6;
            % app.LatitudeEditField.Value = 0;
            % app.LongitudeEditField.Value = 0;
            % app.PotnciaWattsEditField.Value = 0;
            % app.AlturaEditField_2.Value = 1.6;
            % app.txAntenna.Value = '';
            % app.txAntenna.UserData = [];
            % app.AzimuteEditField.Value = 0;
            % app.TiltEditField.Value = 0;
            
            app.prop_terrain.Value = fullfile(app.General.fileFolder.MFilePath, '..', 'tests', 'data', 'terreno_carangola.tif');
            app.FrequnciaMHzEditField.Value = 102.1;
            app.ClutterEditField.Value = '';
            app.AlturaEditField.Value = 1.6;
            app.LatitudeEditField.Value = -20.72568333;
            app.LongitudeEditField.Value = -42.0361666666;
            app.PotnciaWattsEditField.Value = 275;
            app.AlturaEditField_2.Value = 8;
            app.txAntenna.Value = '';
            app.txAntenna.UserData = [];
            app.AzimuteEditField.Value = 0;
            app.TiltEditField.Value = 0;
            figure(app.UIFigure)


            cla(app.UIAxes1);
        end

        % Menu selected function: PerfilMenu
        function PerfilMenuSelected(app, event)
            mouseCliquePos = app.UIAxes1.CurrentPoint;
            app.rxLatitudeEditField.Value = mouseCliquePos(1, 1);
            app.rxLongitudeEditField.Value = mouseCliquePos(1, 2);
            app.tool_RFLinkButton.UserData = true;

            if app.tool_TableVisibility.UserData == 2
                app.tool_TableVisibility.UserData = 1;
                app.GridLayout.RowHeight(2:5) = {23,'1x',10,'.4x'};
                pause(.100)
            end


            set(findobj(app.UIAxes1), 'Visible', 0)
            set(findobj(app.UIAxes2), 'Visible', 1)
            cla(app.UIAxes2);
            app.GridLayout2.RowHeight(8) = {123};
            app.rxLongitudeEditField.Visible = "on";
            app.rxLatitudeEditFieldLabel.Visible = "on";
            app.rxLatitudeEditField.Visible = "on";
            app.rxLongitudeEditFieldLabel.Visible = "on";
            SimularButtonPushed3(app, event);

        end

        % Value changing function: EstaoEditField
        function EstaoEditFieldValueChanging(app, event)
            %changingValue = event.Value;
            %app.EstaoEditField.BackgroundColor = [1 1 1];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'rfPreview';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.panelGrid = uigridlayout(app.GridLayout);
            app.ControlTabGroup = uitabgroup(app.panelGrid);
            app.Tab_1 = uitab(app.ControlTabGroup);
            app.Tab1_Grid = uigridlayout(app.Tab_1);
            app.referenceTV_TitleGrid = uigridlayout(app.Tab1_Grid);
            app.referenceTX_Icon = uiimage(app.referenceTV_TitleGrid);
            app.referenceTX_Icon.ImageSource = 'Pin_32.png';
            app.referenceTX_Label = uilabel(app.referenceTV_TitleGrid);
            app.referenceTX_Label.Interpreter = 'html';
            app.referenceTX_Label.Text = {'<b>Estação transmissora - TX</b>'; '<font style="font-size: 9px; color: gray;">(Registro selecionado em tabela)</font>'};
            app.referenceTX_Refresh = uiimage(app.referenceTV_TitleGrid);
            app.referenceTX_Refresh.ImageClickedFcn = createCallbackFcn(app, @referenceTX_EditionModeImageClicked, true);
            app.referenceTX_Refresh.Visible = 'off';
            app.referenceTX_Refresh.Tooltip = {'Retorna aos valores constantes em base'};
            app.referenceTX_Refresh.ImageSource = 'Refresh_18.png';
            app.referenceTX_EditionMode = uiimage(app.referenceTV_TitleGrid);
            app.referenceTX_EditionMode.ImageClickedFcn = createCallbackFcn(app, @referenceTX_EditionModeImageClicked, true);
            app.referenceTX_EditionMode.Tooltip = {'Habilita painel de edição'};
            app.referenceTX_EditionMode.ImageSource = 'Edit_32.png';
            app.referenceTX_EditionConfirm = uiimage(app.referenceTV_TitleGrid);
            app.referenceTX_EditionConfirm.ImageClickedFcn = createCallbackFcn(app, @referenceTX_EditionModeImageClicked, true);
            app.referenceTX_EditionConfirm.Enable = 'off';
            app.referenceTX_EditionConfirm.Tooltip = {'Confirma edição'};
            app.referenceTX_EditionConfirm.ImageSource = 'Ok_32Green.png';
            app.referenceTX_EditionCancel = uiimage(app.referenceTV_TitleGrid);
            app.referenceTX_EditionCancel.ImageClickedFcn = createCallbackFcn(app, @referenceTX_EditionModeImageClicked, true);
            app.referenceTX_EditionCancel.Enable = 'off';
            app.referenceTX_EditionCancel.Tooltip = {'Cancela edição'};
            app.referenceTX_EditionCancel.ImageSource = 'Delete_32Red.png';
            app.referenceTX_Panel = uipanel(app.Tab1_Grid);
            app.referenceTX_Grid = uigridlayout(app.referenceTX_Panel);
            app.referenceTX_LatitudeLabel = uilabel(app.referenceTX_Grid);
            app.referenceTX_LatitudeLabel.Text = 'Latitude:';
            app.referenceTX_Latitude = uieditfield(app.referenceTX_Grid, 'numeric');
            app.referenceTX_Latitude.Limits = [-90 90];
            app.referenceTX_Latitude.ValueDisplayFormat = '%.6f';
            app.referenceTX_Latitude.Editable = 'off';
            app.referenceTX_Latitude.Value = -1;
            app.referenceTX_LongitudeLabel = uilabel(app.referenceTX_Grid);
            app.referenceTX_LongitudeLabel.Text = 'Longitude:';
            app.referenceTX_Longitude = uieditfield(app.referenceTX_Grid, 'numeric');
            app.referenceTX_Longitude.Limits = [-180 180];
            app.referenceTX_Longitude.ValueDisplayFormat = '%.6f';
            app.referenceTX_Longitude.Editable = 'off';
            app.referenceTX_Longitude.Value = -1;
            app.referenceTX_HeightLabel = uilabel(app.referenceTX_Grid);
            app.referenceTX_HeightLabel.Text = 'Altura (m):';
            app.referenceTX_Height = uieditfield(app.referenceTX_Grid, 'numeric');
            app.referenceTX_Height.Limits = [0 Inf];
            app.referenceTX_Height.ValueDisplayFormat = '%.1f';
            app.referenceTX_Height.Editable = 'off';
            app.stationInfoImage = uiimage(app.Tab1_Grid);
            app.stationInfoImage.Visible = 'off';
            app.stationInfoImage.ImageSource = 'Warn_18.png';
            app.stationInfoAntennaPattern = uipanel(app.Tab1_Grid);
            app.stationInfo = uilabel(app.Tab1_Grid);
            app.stationInfo.Interpreter = 'html';
            app.stationInfo.Text = '';
            app.Tab_2 = uitab(app.ControlTabGroup);
            app.Tab2_Grid = uigridlayout(app.Tab_2);
            app.referenceRX_Icon = uiimage(app.Tab2_Grid);
            app.referenceRX_Icon.ImageSource = 'Pin_32Triangle.png';
            app.referenceRX_Label = uilabel(app.Tab2_Grid);
            app.referenceRX_Label.Interpreter = 'html';
            app.referenceRX_Label.Text = {'<b>Estação receptora - RX</b>'; '<font style="font-size: 9px; color: gray;">(Referência coluna calculada "Distância" e enlace)</font>'};
            app.referenceRX_Refresh = uiimage(app.Tab2_Grid);
            app.referenceRX_Refresh.ImageClickedFcn = createCallbackFcn(app, @referenceRX_EditionModeImageClicked, true);
            app.referenceRX_Refresh.Visible = 'off';
            app.referenceRX_Refresh.Tooltip = {'Retorna aos valores iniciais'};
            app.referenceRX_Refresh.ImageSource = 'Refresh_18.png';
            app.referenceRX_EditionMode = uiimage(app.Tab2_Grid);
            app.referenceRX_EditionMode.ImageClickedFcn = createCallbackFcn(app, @referenceRX_EditionModeImageClicked, true);
            app.referenceRX_EditionMode.Tooltip = {'Habilita painel de edição'};
            app.referenceRX_EditionMode.ImageSource = 'Edit_32.png';
            app.referenceRX_EditionConfirm = uiimage(app.Tab2_Grid);
            app.referenceRX_EditionConfirm.ImageClickedFcn = createCallbackFcn(app, @referenceRX_EditionModeImageClicked, true);
            app.referenceRX_EditionConfirm.Enable = 'off';
            app.referenceRX_EditionConfirm.Tooltip = {'Confirma edição'};
            app.referenceRX_EditionConfirm.ImageSource = 'Ok_32Green.png';
            app.referenceRX_EditionCancel = uiimage(app.Tab2_Grid);
            app.referenceRX_EditionCancel.ImageClickedFcn = createCallbackFcn(app, @referenceRX_EditionModeImageClicked, true);
            app.referenceRX_EditionCancel.Enable = 'off';
            app.referenceRX_EditionCancel.Tooltip = {'Cancela edição'};
            app.referenceRX_EditionCancel.ImageSource = 'Delete_32Red.png';
            app.referenceRX_Panel = uipanel(app.Tab2_Grid);
            app.referenceRX_Grid = uigridlayout(app.referenceRX_Panel);
            app.referenceRX_LatitudeLabel = uilabel(app.referenceRX_Grid);
            app.referenceRX_LatitudeLabel.Text = 'Latitude:';
            app.referenceRX_Latitude = uieditfield(app.referenceRX_Grid, 'numeric');
            app.referenceRX_Latitude.Limits = [-90 90];
            app.referenceRX_Latitude.ValueDisplayFormat = '%.6f';
            app.referenceRX_Latitude.Editable = 'off';
            app.referenceRX_Latitude.Value = -1;
            app.referenceRX_LongitudeLabel = uilabel(app.referenceRX_Grid);
            app.referenceRX_LongitudeLabel.Text = 'Longitude:';
            app.referenceRX_Longitude = uieditfield(app.referenceRX_Grid, 'numeric');
            app.referenceRX_Longitude.Limits = [-180 180];
            app.referenceRX_Longitude.ValueDisplayFormat = '%.6f';
            app.referenceRX_Longitude.Editable = 'off';
            app.referenceRX_Longitude.Value = -1;
            app.referenceRX_HeightLabel = uilabel(app.referenceRX_Grid);
            app.referenceRX_HeightLabel.Text = 'Altura (metros):';
            app.referenceRX_Height = uieditfield(app.referenceRX_Grid, 'numeric');
            app.referenceRX_Height.Limits = [0 Inf];
            app.referenceRX_Height.RoundFractionalValues = 'on';
            app.referenceRX_Height.ValueDisplayFormat = '%d';
            app.referenceRX_Height.Editable = 'off';
            app.filter_SecondaryLabel = uilabel(app.Tab2_Grid);
            app.filter_SecondaryLabel.Text = 'FILTRO';
            app.filter_SecondaryTypePanel = uibuttongroup(app.Tab2_Grid);
            app.filter_SecondaryTypePanel.SelectionChangedFcn = createCallbackFcn(app, @filter_typePanelSelectionChanged, true);
            app.filter_SecondaryType1 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType1.Tag = '16';
            app.filter_SecondaryType1.Text = 'Fonte';
            app.filter_SecondaryType2 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType2.Tag = '1';
            app.filter_SecondaryType2.Text = 'Frequência';
            app.filter_SecondaryType3 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType3.Tag = '13';
            app.filter_SecondaryType3.Text = 'Largura banda';
            app.filter_SecondaryType4 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType4.Tag = '12';
            app.filter_SecondaryType4.Text = 'Classe emissão';
            app.filter_SecondaryType5 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType5.Tag = '2';
            app.filter_SecondaryType5.Text = 'Entidade';
            app.filter_SecondaryType6 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType6.Tag = '3';
            app.filter_SecondaryType6.Text = 'Fistel';
            app.filter_SecondaryType7 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType7.Tag = '4';
            app.filter_SecondaryType7.Text = 'Serviço';
            app.filter_SecondaryType8 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType8.Tag = '5';
            app.filter_SecondaryType8.Text = 'Estação';
            app.filter_SecondaryType9 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType9.Tag = '10';
            app.filter_SecondaryType9.Text = 'UF';
            app.filter_SecondaryType10 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType10.Tag = '9';
            app.filter_SecondaryType10.Text = 'Município';
            app.filter_SecondaryType11 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType11.Tag = '32';
            app.filter_SecondaryType11.Text = 'Distância';
            app.filter_SecondaryType11.Value = true;
            app.filter_SecondaryType12 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType12.Tag = '-1';
            app.filter_SecondaryType12.Text = 'ROI';
            app.filter_SecondaryType13 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType13.Tag = '27';
            app.filter_SecondaryType13.Text = 'Padrão de antena';
            app.filter_SecondaryValuePanel = uibuttongroup(app.Tab2_Grid);
            app.filter_SecondaryValuePanel.SelectionChangedFcn = createCallbackFcn(app, @filter_SecondaryValuePanelSelectionChanged, true);
            app.filter_SecondaryOperation1 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation1.Tag = 'numeric+textFree+textList2';
            app.filter_SecondaryOperation1.Tooltip = {'Igual'};
            app.filter_SecondaryOperation1.Text = '=';
            app.filter_SecondaryOperation2 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation2.Tag = 'numeric+textFree+textList2+textList3';
            app.filter_SecondaryOperation2.Tooltip = {'Diferente'};
            app.filter_SecondaryOperation2.Text = '≠';
            app.filter_SecondaryOperation3 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation3.Tag = 'ROI+textFree+textList1';
            app.filter_SecondaryOperation3.Enable = 'off';
            app.filter_SecondaryOperation3.Tooltip = {'Contém'};
            app.filter_SecondaryOperation3.Text = '⊃';
            app.filter_SecondaryOperation4 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation4.Tag = 'textFree+textList1';
            app.filter_SecondaryOperation4.Enable = 'off';
            app.filter_SecondaryOperation4.Tooltip = {'Não contém'};
            app.filter_SecondaryOperation4.Text = '⊅';
            app.filter_SecondaryOperation5 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation5.Tag = 'numeric';
            app.filter_SecondaryOperation5.Tooltip = {'Menor'};
            app.filter_SecondaryOperation5.Text = '<';
            app.filter_SecondaryOperation6 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation6.Tag = 'numeric';
            app.filter_SecondaryOperation6.Tooltip = {'Menor ou igual'};
            app.filter_SecondaryOperation6.Text = '≤';
            app.filter_SecondaryOperation6.Value = true;
            app.filter_SecondaryOperation7 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation7.Tag = 'numeric';
            app.filter_SecondaryOperation7.Tooltip = {'Maior'};
            app.filter_SecondaryOperation7.Text = '>';
            app.filter_SecondaryOperation8 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation8.Tag = 'numeric';
            app.filter_SecondaryOperation8.Tooltip = {'Maior ou igual'};
            app.filter_SecondaryOperation8.Text = '≥';
            app.filter_SecondaryOperation9 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation9.Tag = 'numeric';
            app.filter_SecondaryOperation9.Tooltip = {'Dentro do intervalo'};
            app.filter_SecondaryOperation9.Text = '><';
            app.filter_SecondaryOperation10 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation10.Tag = 'numeric';
            app.filter_SecondaryOperation10.Tooltip = {'Fora do intervalo'};
            app.filter_SecondaryOperation10.Text = '<>';
            app.filter_SecondaryValueSubpanel = uipanel(app.filter_SecondaryValuePanel);
            app.filter_SecondaryValueGrid = uigridlayout(app.filter_SecondaryValueSubpanel);
            app.filter_SecondaryNumValue1 = uieditfield(app.filter_SecondaryValueGrid, 'numeric');
            app.filter_SecondaryNumValue1.Limits = [-1 Inf];
            app.filter_SecondaryNumValue1.ValueDisplayFormat = '%10.10g';
            app.filter_SecondaryNumValue1.Visible = 'off';
            app.filter_SecondaryNumValue1.Value = 30;
            app.filter_SecondaryNumSeparator = uilabel(app.filter_SecondaryValueGrid);
            app.filter_SecondaryNumSeparator.Visible = 'off';
            app.filter_SecondaryNumSeparator.Text = '-';
            app.filter_SecondaryNumValue2 = uieditfield(app.filter_SecondaryValueGrid, 'numeric');
            app.filter_SecondaryNumValue2.Limits = [-1 Inf];
            app.filter_SecondaryNumValue2.Visible = 'off';
            app.filter_SecondaryNumValue2.Value = -1;
            app.filter_SecondaryTextFree = uieditfield(app.filter_SecondaryValueGrid, 'text');
            app.filter_SecondaryTextFree.Visible = 'off';
            app.filter_SecondaryTextList = uidropdown(app.filter_SecondaryValueGrid);
            app.filter_SecondaryTextList.Items = {};
            app.filter_SecondaryTextList.Value = {};
            app.filter_SecondaryReferenceFilterLabel = uilabel(app.filter_SecondaryValueGrid);
            app.filter_SecondaryReferenceFilterLabel.Text = {'Filtro de '; 'referência (ID):'};
            app.filter_SecondaryReferenceFilter = uidropdown(app.filter_SecondaryValueGrid);
            app.filter_SecondaryReferenceFilter.Items = {};
            app.filter_SecondaryReferenceFilter.ValueChangedFcn = createCallbackFcn(app, @filter_SecondaryReferenceFilterValueChanged, true);
            app.filter_SecondaryReferenceFilter.Value = {};
            app.filter_SecondaryLogicalOperatorLabel = uilabel(app.filter_SecondaryValueGrid);
            app.filter_SecondaryLogicalOperatorLabel.Text = {'Operador '; 'lógico:'};
            app.filter_SecondaryLogicalOperator = uidropdown(app.filter_SecondaryValueGrid);
            app.filter_SecondaryLogicalOperator.Items = {'E (&&)'};
            app.filter_SecondaryLogicalOperator.Value = 'E (&&)';
            app.filter_AddImage = uiimage(app.Tab2_Grid);
            app.filter_AddImage.ImageClickedFcn = createCallbackFcn(app, @filter_addFilter, true);
            app.filter_AddImage.Tooltip = {'Adicionar filtro'};
            app.filter_AddImage.ImageSource = 'addSymbol_32.png';
            app.filter_Tree = uitree(app.Tab2_Grid, 'checkbox');
            % Assign Checked Nodes
            app.filter_Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @filter_TreeCheckedNodesChanged, true);
            app.Tab_3 = uitab(app.ControlTabGroup);
            app.Tab3_Grid = uigridlayout(app.Tab_3);
            app.config_geoAxesLabel = uilabel(app.Tab3_Grid);
            app.config_geoAxesLabel.Text = 'EIXO GEOGRÁFICO';
            app.config_Refresh = uiimage(app.Tab3_Grid);
            app.config_Refresh.ImageClickedFcn = createCallbackFcn(app, @config_RefreshImageClicked, true);
            app.config_Refresh.Visible = 'off';
            app.config_Refresh.Tooltip = {'Volta à configuração inicial'};
            app.config_Refresh.ImageSource = 'Refresh_18.png';
            app.config_geoAxesPanel = uipanel(app.Tab3_Grid);
            app.config_geoAxesGrid = uigridlayout(app.config_geoAxesPanel);
            app.config_geoAxesSublabel = uilabel(app.config_geoAxesGrid);
            app.config_geoAxesSublabel.Text = 'Mapa:';
            app.config_geoAxesSubPanel = uipanel(app.config_geoAxesGrid);
            app.config_geoAxesSubGrid = uigridlayout(app.config_geoAxesSubPanel);
            app.config_BasemapLabel = uilabel(app.config_geoAxesSubGrid);
            app.config_BasemapLabel.Text = 'Basemap:';
            app.config_Basemap = uidropdown(app.config_geoAxesSubGrid);
            app.config_Basemap.Items = {'none', 'darkwater', 'streets-light', 'streets-dark', 'satellite', 'topographic', 'grayterrain'};
            app.config_Basemap.ValueChangedFcn = createCallbackFcn(app, @config_geoAxesOthersParametersChanged, true);
            app.config_Basemap.Value = 'satellite';
            app.config_ColormapLabel = uilabel(app.config_geoAxesSubGrid);
            app.config_ColormapLabel.Text = 'Mapa de cor:';
            app.config_Colormap = uidropdown(app.config_geoAxesSubGrid);
            app.config_Colormap.Items = {'winter', 'parula', 'turbo', 'gray', 'hot', 'jet', 'summer'};
            app.config_Colormap.ValueChangedFcn = createCallbackFcn(app, @config_geoAxesOthersParametersChanged, true);
            app.config_Colormap.Value = 'winter';
            app.config_Station_Label = uilabel(app.config_geoAxesGrid);
            app.config_Station_Label.Text = 'Estações RFDataHub:';
            app.config_Station_Color = uicolorpicker(app.config_geoAxesGrid);
            app.config_Station_Color.Value = [0 1 1];
            app.config_Station_Color.ValueChangedFcn = createCallbackFcn(app, @config_geoAxesColorParameterChanged, true);
            app.config_Station_Size = uislider(app.config_geoAxesGrid);
            app.config_Station_Size.Limits = [1 36];
            app.config_Station_Size.MajorTicks = [];
            app.config_Station_Size.ValueChangingFcn = createCallbackFcn(app, @config_geoAxesOthersParametersChanged, true);
            app.config_Station_Size.MinorTicks = [1 3 5 7 9 11 13 15 17 19 21 23 25 27 29 31 33 36];
            app.config_Station_Size.Tooltip = {'Tamanho do marcador'};
            app.config_Station_Size.Value = 1;
            app.config_TX_Label = uilabel(app.config_geoAxesGrid);
            app.config_TX_Label.Text = 'Estação transmissora - TX:';
            app.config_TX_Color = uicolorpicker(app.config_geoAxesGrid);
            app.config_TX_Color.Value = [0.7882 0.2784 0.3412];
            app.config_TX_Color.ValueChangedFcn = createCallbackFcn(app, @config_geoAxesColorParameterChanged, true);
            app.config_TX_Size = uislider(app.config_geoAxesGrid);
            app.config_TX_Size.Limits = [1 3];
            app.config_TX_Size.MajorTicks = [];
            app.config_TX_Size.ValueChangingFcn = createCallbackFcn(app, @config_geoAxesOthersParametersChanged, true);
            app.config_TX_Size.MinorTicks = [1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3];
            app.config_TX_Size.Tooltip = {'Tamanho do marcador'};
            app.config_TX_Size.Value = 1;
            app.config_TX_DataTipVisibility = uidropdown(app.config_geoAxesGrid);
            app.config_TX_DataTipVisibility.Items = {'on', 'off'};
            app.config_TX_DataTipVisibility.ValueChangedFcn = createCallbackFcn(app, @config_geoAxesOthersParametersChanged, true);
            app.config_TX_DataTipVisibility.Tooltip = {'Datatip'};
            app.config_TX_DataTipVisibility.Value = 'off';
            app.config_RX_Label = uilabel(app.config_geoAxesGrid);
            app.config_RX_Label.Text = 'Estação receptora - RX:';
            app.config_RX_Color = uicolorpicker(app.config_geoAxesGrid);
            app.config_RX_Color.Value = [0.7882 0.2784 0.3373];
            app.config_RX_Color.ValueChangedFcn = createCallbackFcn(app, @config_geoAxesColorParameterChanged, true);
            app.config_RX_Size = uislider(app.config_geoAxesGrid);
            app.config_RX_Size.Limits = [1 3];
            app.config_RX_Size.MajorTicks = [];
            app.config_RX_Size.ValueChangingFcn = createCallbackFcn(app, @config_geoAxesOthersParametersChanged, true);
            app.config_RX_Size.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3];
            app.config_RX_Size.Tooltip = {'Tamanho do marcador'};
            app.config_RX_Size.Value = 1;
            app.misc_ElevationSourceLabel = uilabel(app.Tab3_Grid);
            app.misc_ElevationSourceLabel.Text = 'ELEVAÇÃO';
            app.misc_ElevationSourcePanel = uipanel(app.Tab3_Grid);
            app.misc_ElevationSourceGrid = uigridlayout(app.misc_ElevationSourcePanel);
            app.misc_ElevationAPISourceLabel = uilabel(app.misc_ElevationSourceGrid);
            app.misc_ElevationAPISourceLabel.Text = 'Fonte:';
            app.misc_ElevationAPISource = uidropdown(app.misc_ElevationSourceGrid);
            app.misc_ElevationAPISource.Items = {'Open-Elevation', 'MathWorks WMS Server'};
            app.misc_ElevationAPISource.Value = 'Open-Elevation';
            app.misc_ElevationNPointsLabel = uilabel(app.misc_ElevationSourceGrid);
            app.misc_ElevationNPointsLabel.Text = 'Pontos enlace:';
            app.misc_ElevationNPoints = uidropdown(app.misc_ElevationSourceGrid);
            app.misc_ElevationNPoints.Items = {'64', '128', '256', '512', '1024'};
            app.misc_ElevationNPoints.Value = '256';
            app.misc_ElevationForceSearch = uicheckbox(app.misc_ElevationSourceGrid);
            app.misc_ElevationForceSearch.Text = 'Ignora informações em cache, forçando uma consulta ao servidor.';
            app.Tab_4 = uitab(app.ControlTabGroup);
            app.GridLayout2 = uigridlayout(app.Tab_4);
            app.prop_ModelLabel = uilabel(app.GridLayout2);
            app.prop_ModelLabel.Text = 'Modelo:';
            app.prop_Model = uidropdown(app.GridLayout2);
            app.prop_Model.Items = {};
            app.prop_Model.Value = {};
            app.RaiometrosEditFieldLabel = uilabel(app.GridLayout2);
            app.RaiometrosEditFieldLabel.Text = 'Raio (metros):';
            app.RaiometrosEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RaiometrosEditField.Limits = [0 Inf];
            app.RaiometrosEditField.Value = 5000;
            app.prop_terrainLabel = uilabel(app.GridLayout2);
            app.prop_terrainLabel.Text = 'Relevo:';
            app.prop_terrainButton = uiimage(app.GridLayout2);
            app.prop_terrainButton.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.prop_terrainButton.ImageSource = 'OpenFile_36x36.png';
            app.prop_terrain = uieditfield(app.GridLayout2, 'text');
            app.prop_terrain.Editable = 'off';
            app.ClutterEditFieldLabel = uilabel(app.GridLayout2);
            app.ClutterEditFieldLabel.Text = 'Clutter:';
            app.clutterButton = uiimage(app.GridLayout2);
            app.clutterButton.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.clutterButton.ImageSource = 'OpenFile_36x36.png';
            app.ClutterEditField = uieditfield(app.GridLayout2, 'text');
            app.ClutterEditField.Editable = 'off';
            app.EstaoreceptoraLabel = uilabel(app.GridLayout2);
            app.EstaoreceptoraLabel.Text = 'Estação receptora:';
            app.Panel_2 = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.AlturaEditFieldLabel = uilabel(app.GridLayout3);
            app.AlturaEditFieldLabel.Text = 'Altura:';
            app.AlturaEditField = uieditfield(app.GridLayout3, 'numeric');
            app.AlturaEditField.Limits = [0 Inf];
            app.AlturaEditField.Value = 1.6;
            app.ArquivoantenaEditFieldLabel = uilabel(app.GridLayout3);
            app.ArquivoantenaEditFieldLabel.Enable = 'off';
            app.ArquivoantenaEditFieldLabel.Text = 'Arquivo antena:';
            app.rxAntennaButton = uiimage(app.GridLayout3);
            app.rxAntennaButton.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.rxAntennaButton.Enable = 'off';
            app.rxAntennaButton.ImageSource = 'OpenFile_36x36.png';
            app.ArquivoantenaEditField = uieditfield(app.GridLayout3, 'text');
            app.ArquivoantenaEditField.Editable = 'off';
            app.ArquivoantenaEditField.Enable = 'off';
            app.rxLatitudeEditFieldLabel = uilabel(app.GridLayout3);
            app.rxLatitudeEditFieldLabel.Visible = 'off';
            app.rxLatitudeEditFieldLabel.Text = 'Latitude:';
            app.rxLongitudeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.rxLongitudeEditField.Visible = 'off';
            app.rxLongitudeEditFieldLabel = uilabel(app.GridLayout3);
            app.rxLongitudeEditFieldLabel.Visible = 'off';
            app.rxLongitudeEditFieldLabel.Text = 'Longitude:';
            app.rxLatitudeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.rxLatitudeEditField.Visible = 'off';
            app.EstaotransmissoraLabel = uilabel(app.GridLayout2);
            app.EstaotransmissoraLabel.Text = 'Estação transmissora:';
            app.Panel_3 = uipanel(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.Panel_3);
            app.EstaoEditFieldLabel = uilabel(app.GridLayout4);
            app.EstaoEditFieldLabel.Text = 'Estação:';
            app.EstaoEditField = uieditfield(app.GridLayout4, 'text');
            app.EstaoEditField.ValueChangedFcn = createCallbackFcn(app, @EstaoEditFieldValueChanged, true);
            app.EstaoEditField.ValueChangingFcn = createCallbackFcn(app, @EstaoEditFieldValueChanging, true);
            app.IdentificaoEditFieldLabel = uilabel(app.GridLayout4);
            app.IdentificaoEditFieldLabel.Text = 'Identificação:';
            app.IdentificaoEditField = uieditfield(app.GridLayout4, 'text');
            app.FrequnciaMHzEditFieldLabel = uilabel(app.GridLayout4);
            app.FrequnciaMHzEditFieldLabel.Text = 'Frequência (MHz):';
            app.FrequnciaMHzEditField = uieditfield(app.GridLayout4, 'numeric');
            app.PotnciaWattsEditFieldLabel = uilabel(app.GridLayout4);
            app.PotnciaWattsEditFieldLabel.Text = 'Potência (Watts):';
            app.PotnciaWattsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.LatitudeEditFieldLabel = uilabel(app.GridLayout4);
            app.LatitudeEditFieldLabel.Text = 'Latitude:';
            app.LatitudeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.LongitudeEditFieldLabel = uilabel(app.GridLayout4);
            app.LongitudeEditFieldLabel.Text = 'Longitude:';
            app.LongitudeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.AntenaLabel = uilabel(app.GridLayout4);
            app.AntenaLabel.Text = 'Antena:';
            app.Panel_4 = uipanel(app.GridLayout4);
            app.GridLayout5 = uigridlayout(app.Panel_4);
            app.AlturaEditField_2Label = uilabel(app.GridLayout5);
            app.AlturaEditField_2Label.Text = 'Altura:';
            app.AlturaEditField_2 = uieditfield(app.GridLayout5, 'numeric');
            app.AlturaEditField_2.Limits = [0 Inf];
            app.AlturaEditField_2.Value = 1.6;
            app.TiltEditFieldLabel = uilabel(app.GridLayout5);
            app.TiltEditFieldLabel.Text = 'Tilt:';
            app.TiltEditField = uieditfield(app.GridLayout5, 'numeric');
            app.TiltEditField.Limits = [-90 90];
            app.AzimuteEditFieldLabel = uilabel(app.GridLayout5);
            app.AzimuteEditFieldLabel.Text = 'Azimute:';
            app.AzimuteEditField = uieditfield(app.GridLayout5, 'numeric');
            app.AzimuteEditField.Limits = [0 360];
            app.txAntennaLabel = uilabel(app.GridLayout5);
            app.txAntennaLabel.Text = 'Arquivo:';
            app.txAntennaButton = uiimage(app.GridLayout5);
            app.txAntennaButton.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.txAntennaButton.ImageSource = 'OpenFile_36x36.png';
            app.txAntenna = uieditfield(app.GridLayout5, 'text');
            app.txAntenna.Editable = 'off';
            app.menu_MainGrid = uigridlayout(app.panelGrid);
            app.menuUnderline = uiimage(app.menu_MainGrid);
            app.menuUnderline.ImageSource = 'LineH.png';
            app.menu_Button1Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button1Label = uilabel(app.menu_Button1Grid);
            app.menu_Button1Label.Text = 'RFDATAHUB';
            app.menu_Button1Icon = uiimage(app.menu_Button1Grid);
            app.menu_Button1Icon.ImageClickedFcn = createCallbackFcn(app, @general_ControlPanelSelectionChanged, true);
            app.menu_Button1Icon.Tag = '1';
            app.menu_Button1Icon.ImageSource = 'mosaic_18Gray.png';
            app.menu_Button2Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button2Label = uilabel(app.menu_Button2Grid);
            app.menu_Button2Label.Text = 'FILTRAGEM';
            app.menu_Button2Icon = uiimage(app.menu_Button2Grid);
            app.menu_Button2Icon.ImageClickedFcn = createCallbackFcn(app, @general_ControlPanelSelectionChanged, true);
            app.menu_Button2Icon.Tag = '2';
            app.menu_Button2Icon.ImageSource = 'Filter_18.png';
            app.menu_Button3Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button3Label = uilabel(app.menu_Button3Grid);
            app.menu_Button3Label.Text = 'CONFIGURAÇÕES GERAIS';
            app.menu_Button3Icon = uiimage(app.menu_Button3Grid);
            app.menu_Button3Icon.ImageClickedFcn = createCallbackFcn(app, @general_ControlPanelSelectionChanged, true);
            app.menu_Button3Icon.Tag = '3';
            app.menu_Button3Icon.ImageSource = 'Settings_18.png';
            app.menu_Button4Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button4Label = uilabel(app.menu_Button4Grid);
            app.menu_Button4Label.Text = 'CONFIGURAÇÕES GERAIS';
            app.menu_Button4Icon = uiimage(app.menu_Button4Grid);
            app.menu_Button4Icon.ImageClickedFcn = createCallbackFcn(app, @general_ControlPanelSelectionChanged, true);
            app.menu_Button4Icon.Tag = '4';
            app.menu_Button4Icon.ImageSource = 'Report_18.png';
            app.plotPanel = uipanel(app.GridLayout);
            app.chReportHTML = uihtml(app.GridLayout);
            app.chReportHTML.HTMLSource = 'Warning2.html';
            app.chReportDownloadTime = uilabel(app.GridLayout);
            app.chReportDownloadTime.Text = '';
            app.chReportHotDownload = uiimage(app.GridLayout);
            app.chReportHotDownload.ImageClickedFcn = createCallbackFcn(app, @tool_chReportPanelImageClicked, true);
            app.chReportHotDownload.Tooltip = {'Baixa versão atual do documento'};
            app.chReportHotDownload.ImageSource = 'Refresh_18White.png';
            app.chReportUndock = uiimage(app.GridLayout);
            app.chReportUndock.ImageClickedFcn = createCallbackFcn(app, @tool_chReportPanelImageClicked, true);
            app.chReportUndock.Tooltip = {'Abre documento em leitor externo'};
            app.chReportUndock.ImageSource = 'Undock_18White.png';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'ID'; 'FREQUÊNCIA|(MHz)'; 'DESCRIÇÃO|(Entidade+Fistel+Multiplicidade+Localidade)'; 'SERVIÇO'; 'ESTAÇÃO'; 'LARGURA|(kHz)'; 'DISTÂNCIA|(km)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true false true true true true];
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.toolGrid = uigridlayout(app.GridLayout);
            app.tool_ControlPanelVisibility = uiimage(app.toolGrid);
            app.tool_ControlPanelVisibility.ImageClickedFcn = createCallbackFcn(app, @tool_InteractionImageClicked, true);
            app.tool_ControlPanelVisibility.ImageSource = 'ArrowLeft_32.png';
            app.tool_TableVisibility = uiimage(app.toolGrid);
            app.tool_TableVisibility.ImageClickedFcn = createCallbackFcn(app, @tool_InteractionImageClicked, true);
            app.tool_TableVisibility.Tooltip = {'Visibilidade da tabela'};
            app.tool_TableVisibility.ImageSource = 'View_16.png';
            app.tool_RFLinkButton = uiimage(app.toolGrid);
            app.tool_RFLinkButton.ImageClickedFcn = createCallbackFcn(app, @tool_InteractionImageClicked, true);
            app.tool_RFLinkButton.Tooltip = {'Perfil de terreno até um ponto de interesse e detalhamento do calculo de predição'};
            app.tool_RFLinkButton.ImageSource = 'Publish_HTML_16.png';
            app.tool_PDFButton = uiimage(app.toolGrid);
            app.tool_PDFButton.ImageClickedFcn = createCallbackFcn(app, @tool_InteractionImageClicked, true);
            app.tool_PDFButton.Tooltip = {'Documento relacionado ao registro selecionado'; '(limitado à radiodifusão)'};
            app.tool_PDFButton.ImageSource = 'Publish_PDF_16.png';
            app.tool_Separator = uiimage(app.toolGrid);
            app.tool_Separator.Enable = 'off';
            app.tool_Separator.ImageSource = 'LineV.png';
            app.tool_ExportButton = uiimage(app.toolGrid);
            app.tool_ExportButton.ImageClickedFcn = createCallbackFcn(app, @tool_exportButtonPushed, true);
            app.tool_ExportButton.ImageSource = 'Export_16.png';
            app.tool_tableNRows = uilabel(app.toolGrid);
            app.tool_tableNRows.Text = '';
            app.tool_tableNRowsIcon = uiimage(app.toolGrid);
            app.tool_tableNRowsIcon.Enable = 'off';
            app.tool_tableNRowsIcon.ImageSource = 'Filter_18.png';
            app.SimularButton = uibutton(app.toolGrid, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed3, true);
            app.SimularButton.Icon = 'play_32.png';
            app.SimularButton.Text = 'Simular';
            app.LimparButton = uibutton(app.toolGrid, 'push');
            app.LimparButton.ButtonPushedFcn = createCallbackFcn(app, @LimparButtonPushed, true);
            app.LimparButton.Icon = 'Trash_32.png';
            app.LimparButton.Text = 'Limpar';
            app.axesToolbarGrid = uigridlayout(app.GridLayout);
            app.axesTool_RestoreView = uiimage(app.axesToolbarGrid);
            app.axesTool_RestoreView.ImageClickedFcn = createCallbackFcn(app, @axesTool_InteractionImageClicked, true);
            app.axesTool_RestoreView.Tooltip = {'RestoreView'};
            app.axesTool_RestoreView.ImageSource = 'Home_18.png';
            app.axesTool_RegionZoom = uiimage(app.axesToolbarGrid);
            app.axesTool_RegionZoom.ImageClickedFcn = createCallbackFcn(app, @axesTool_InteractionImageClicked, true);
            app.axesTool_RegionZoom.Tooltip = {'RegionZoom'};
            app.axesTool_RegionZoom.ImageSource = 'ZoomRegion_20.png';
            app.axesTool_ChangeMapLayer = uiimage(app.axesToolbarGrid);
            app.axesTool_ChangeMapLayer.ImageClickedFcn = createCallbackFcn(app, @axesTool_ChangeMapLayerImageClicked, true);
            app.axesTool_ChangeMapLayer.Tooltip = {'Map Layer'};
            app.axesTool_ChangeMapLayer.ImageSource = 'Layers_18.png';
            app.baseMapIdx = uieditfield(app.UIFigure, 'numeric');
            app.baseMapIdx.Limits = [1 Inf];
            app.baseMapIdx.Editable = 'off';
            app.baseMapIdx.Visible = 'off';
            app.baseMapIdx.Value = 1;
            app.filter_ContextMenu = uicontextmenu(app.UIFigure);
            app.filter_delButton = uimenu(app.filter_ContextMenu);
            app.filter_delButton.MenuSelectedFcn = createCallbackFcn(app, @filter_delFilter, true);
            app.filter_delButton.Text = 'Excluir';
            app.filter_delAllButton = uimenu(app.filter_ContextMenu);
            app.filter_delAllButton.MenuSelectedFcn = createCallbackFcn(app, @filter_delFilter, true);
            app.filter_delAllButton.Text = 'Excluir todos';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.PerfilMenu = uimenu(app.ContextMenu);
            app.PerfilMenu.MenuSelectedFcn = createCallbackFcn(app, @PerfilMenuSelected, true);
            app.PerfilMenu.Text = 'Perfil';
            app.Menu = uimenu(app.ContextMenu);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winPropagation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end